classdef SqueakPeekStudio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SqueakPeekStudioUIFigure        matlab.ui.Figure
        ButtonsMenuPanel                matlab.ui.container.Panel
        ButtonsMenuGrid                 matlab.ui.container.GridLayout
        VisualizationButton             matlab.ui.control.Button
        MetricsButton                   matlab.ui.control.Button
        InfoButton                      matlab.ui.control.Button
        SettingsButton                  matlab.ui.control.Button
        VideoSyncButton                 matlab.ui.control.Button
        LabelEditButton                 matlab.ui.control.Button
        DetectionButton                 matlab.ui.control.Button
        DataInputButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DataInputTab                    matlab.ui.container.Tab
        DataInputPanel                  matlab.ui.container.Panel
        DataInputGrid                   matlab.ui.container.GridLayout
        ExplainerDataInput              matlab.ui.control.Label
        BatchModePanel                  matlab.ui.container.Panel
        BatchModeGridGrid               matlab.ui.container.GridLayout
        SelectDetectedLabelDropDown     matlab.ui.control.DropDown
        SelectDetectedLabelDropDownLabel  matlab.ui.control.Label
        SelectReferenceLabelDropDown    matlab.ui.control.DropDown
        SelectReferenceLabelDropDownLabel  matlab.ui.control.Label
        SelectUSVDropDown               matlab.ui.control.DropDown
        SelectUSVDropDownLabel          matlab.ui.control.Label
        BatchModeLabelText              matlab.ui.control.Label
        VideoBatchFound                 matlab.ui.control.Label
        ReferenceLabelBatchFound        matlab.ui.control.Label
        LabelBatchFound                 matlab.ui.control.Label
        USVBatchFound                   matlab.ui.control.Label
        SelectUSVFieldBatch             matlab.ui.control.EditField
        SelectVideoFieldBatch           matlab.ui.control.EditField
        SelectReferenceLabelFieldBatch  matlab.ui.control.EditField
        SelectLabelFieldBatch           matlab.ui.control.EditField
        SelectVideomp4ButtonBatch       matlab.ui.control.Button
        SelectReferenceLabeltxtButtonBatch  matlab.ui.control.Button
        SelectLabeltxtButtonBatch       matlab.ui.control.Button
        SelectUSVButtonBatch            matlab.ui.control.Button
        SingleModePanel                 matlab.ui.container.Panel
        SingleModeGridGrid              matlab.ui.container.GridLayout
        SelectVideoFieldSingle          matlab.ui.control.EditField
        SelectVideoButtonSingle         matlab.ui.control.Button
        SelectReferenceLabelButtonSingle  matlab.ui.control.Button
        SelectReferenceLabelFieldSingle  matlab.ui.control.EditField
        SelectLabelFieldSingle          matlab.ui.control.EditField
        SelectLabelButtonSingle         matlab.ui.control.Button
        SelectUSVFieldSingle            matlab.ui.control.EditField
        SelectUSVButtonSingle           matlab.ui.control.Button
        SingleBatchModeButton           matlab.ui.control.Button
        LoadFilesButton                 matlab.ui.control.Button
        VisualizationTab                matlab.ui.container.Tab
        VisualizationPanel              matlab.ui.container.Panel
        VisualizationGrid               matlab.ui.container.GridLayout
        Label_5                         matlab.ui.control.Label
        SegmentLengthsEditField         matlab.ui.control.NumericEditField
        SegmentLengthsEditFieldLabel    matlab.ui.control.Label
        SegmentStartsEditField          matlab.ui.control.NumericEditField
        SegmentStartsEditFieldLabel     matlab.ui.control.Label
        SonifyButton                    matlab.ui.control.Button
        SegmentMoveForward              matlab.ui.control.Button
        SegmentMoveBackward             matlab.ui.control.Button
        SpectrogramPlot                 matlab.ui.container.Panel
        SpectrogramGrid                 matlab.ui.container.GridLayout
        PlotButton                      matlab.ui.control.Button
        SpectrogramUIAxes               matlab.ui.control.UIAxes
        WaveformPlot                    matlab.ui.container.Panel
        WaveformGrid                    matlab.ui.container.GridLayout
        WaveformUIAxes                  matlab.ui.control.UIAxes
        MetricsTab                      matlab.ui.container.Tab
        ExportPanel                     matlab.ui.container.Panel
        ExportGrid                      matlab.ui.container.GridLayout
        Label_4                         matlab.ui.control.Label
        DetectionmetricsLabel           matlab.ui.control.Label
        DetectedLabelsMetricsEditField  matlab.ui.control.NumericEditField
        DetectedLabelsEditField_5Label  matlab.ui.control.Label
        F1ScoreMetricsEditField         matlab.ui.control.NumericEditField
        F1ScoreEditFieldLabel_2         matlab.ui.control.Label
        RecallMetricsEditField          matlab.ui.control.NumericEditField
        RecallEditFieldLabel_2          matlab.ui.control.Label
        PrecisionMetricsEditField       matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel_2       matlab.ui.control.Label
        FalseNegativesMetricsEditField  matlab.ui.control.NumericEditField
        FalseNegativesEditFieldLabel_2  matlab.ui.control.Label
        TruePositivesMetricsEditField   matlab.ui.control.NumericEditField
        DetectedLabelsEditField_2Label_4  matlab.ui.control.Label
        ReferenceLabelsMetricsEditField  matlab.ui.control.NumericEditField
        DetectedLabelsEditField_2Label_3  matlab.ui.control.Label
        ReloadMetricsButton             matlab.ui.control.Button
        DetectionTab                    matlab.ui.container.Tab
        DetectionPanel                  matlab.ui.container.Panel
        DetectionGrid                   matlab.ui.container.GridLayout
        ExplainerDetection              matlab.ui.control.Label
        UsedPostProcessingListBox       matlab.ui.control.ListBox
        PostProcessingLabel             matlab.ui.control.Label
        SelectDetectorsExportFolderEditField  matlab.ui.control.EditField
        SelectDetectorsExportFolder     matlab.ui.control.Button
        UsedDetectorsListBox            matlab.ui.control.ListBox
        DetectorsListBoxLabel           matlab.ui.control.Label
        RunDetectorsButton              matlab.ui.control.Button
        LabelEditTab                    matlab.ui.container.Tab
        LabelEditPanel                  matlab.ui.container.Panel
        LabelEditGrid                   matlab.ui.container.GridLayout
        EditLabelClassificationPanel    matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        EditClassificationLabel         matlab.ui.control.Label
        ClassificationDropdown          matlab.ui.control.DropDown
        EditLabelClassificationEditField  matlab.ui.control.EditField
        ClassificationStateDropdown     matlab.ui.control.DropDown
        ACEditFieldLabel                matlab.ui.control.Label
        AcceptedClassificationsEditField  matlab.ui.control.NumericEditField
        RCEditFieldLabel                matlab.ui.control.Label
        RejectedClassificationsEditField  matlab.ui.control.NumericEditField
        RejectClassificationButton      matlab.ui.control.Button
        AcceptClassificationButton      matlab.ui.control.Button
        RejectedLabelsEditFieldLabel    matlab.ui.control.Label
        AcceptedLabelsEditFieldLabel    matlab.ui.control.Label
        RejectedDetectionsEditField     matlab.ui.control.NumericEditField
        AcceptedDetectionsEditField     matlab.ui.control.NumericEditField
        DetectionStateDropDown          matlab.ui.control.DropDown
        ExplainerLabelEdit              matlab.ui.control.Label
        ExportLabelsButton              matlab.ui.control.Button
        LoadLabelEditButton             matlab.ui.control.Button
        LabelEditAllLabels              matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        LabelEditCurrentLabel           matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        LabelEditMoveForward            matlab.ui.control.Button
        LabelEditMoveBackward           matlab.ui.control.Button
        RejectDetectionButton           matlab.ui.control.Button
        AcceptDetectionButton           matlab.ui.control.Button
        LabelEditSpectrogramUIAxes      matlab.ui.control.UIAxes
        VideoSyncTab                    matlab.ui.container.Tab
        VideoSyncPanel                  matlab.ui.container.Panel
        VideoSyncGrid                   matlab.ui.container.GridLayout
        ExplainerVideoSync              matlab.ui.control.Label
        AudioOutputDropDown             matlab.ui.control.DropDown
        AudioOutputDropDownLabel        matlab.ui.control.Label
        SynchronizeVideoButton          matlab.ui.control.Button
        sLabel                          matlab.ui.control.Label
        SynchronizationTimeinVideoEditField  matlab.ui.control.NumericEditField
        SynchronizationTimeinVideoEditFieldLabel  matlab.ui.control.Label
        SelectVideoButtonSingle_2       matlab.ui.control.Button
        SelectVideoFieldSingle_2        matlab.ui.control.EditField
        SettingsTab                     matlab.ui.container.Tab
        SettingsMenuPanel               matlab.ui.container.Panel
        SettingsMenuGrid                matlab.ui.container.GridLayout
        SettingsText                    matlab.ui.control.Label
        SettingsDropDown                matlab.ui.control.DropDown
        SettingsDropDownLabel           matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        SettingsGrid                    matlab.ui.container.GridLayout
        SettingsInsideTab               matlab.ui.container.TabGroup
        DataInputSettingsTab            matlab.ui.container.Tab
        DataInputSettingsGrid           matlab.ui.container.GridLayout
        SingleBatchModeButton_2         matlab.ui.control.Button
        DetectormodeLabel               matlab.ui.control.Label
        VisualizationSettingsTab        matlab.ui.container.Tab
        VisualizationSettingsGrid       matlab.ui.container.GridLayout
        ManualLabelLengthEditField      matlab.ui.control.NumericEditField
        ManualLabelLengthEditFieldLabel  matlab.ui.control.Label
        SonificationSlowdownEditField   matlab.ui.control.NumericEditField
        SonificationSlowdownEditFieldLabel  matlab.ui.control.Label
        SonificationSTEditField         matlab.ui.control.NumericEditField
        SonificationSTEditFieldLabel    matlab.ui.control.Label
        ReferenceLabelColorDropDown     matlab.ui.control.DropDown
        LabelColorDropDown_2Label       matlab.ui.control.Label
        LabelColorDropDown              matlab.ui.control.DropDown
        LabelColorDropDownLabel         matlab.ui.control.Label
        SpectrogramColormapDropDown     matlab.ui.control.DropDown
        SpectrogramColormapDropDownLabel  matlab.ui.control.Label
        ShowReferenceLabelsCheckBox     matlab.ui.control.CheckBox
        ShowLabelsCheckBox              matlab.ui.control.CheckBox
        kHzLabel_2                      matlab.ui.control.Label
        kHzLabel                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SpectrogramMaximumFrequencyEditField  matlab.ui.control.NumericEditField
        SpectrogramMaximumFrequencyEditFieldLabel  matlab.ui.control.Label
        SpectrogramMinimumFrequencyEditField  matlab.ui.control.NumericEditField
        SpectrogramMinimumFrequencyEditFieldLabel  matlab.ui.control.Label
        SpectrogramWindowOverlapEditField  matlab.ui.control.NumericEditField
        SpectrogramWindowOverlapEditFieldLabel  matlab.ui.control.Label
        SpectrogramWindowSizeEditField  matlab.ui.control.NumericEditField
        SpectrogramWindowSizeEditFieldLabel  matlab.ui.control.Label
        ShowLoadingDialogueCheckBox     matlab.ui.control.CheckBox
        DetectionSettingsTab            matlab.ui.container.Tab
        DetectionSettingsGrid           matlab.ui.container.GridLayout
        DetectionSettingsIndividualGroup  matlab.ui.container.TabGroup
        PSDTab                          matlab.ui.container.Tab
        DetectionSettingsGrid_2         matlab.ui.container.GridLayout
        PSD_MinPowerEditField           matlab.ui.control.NumericEditField
        MinEffectivePowerLabel          matlab.ui.control.Label
        PSD_wEditField                  matlab.ui.control.NumericEditField
        wEditFieldLabel                 matlab.ui.control.Label
        PSD_kEditField                  matlab.ui.control.NumericEditField
        kEditFieldLabel                 matlab.ui.control.Label
        PSD_LocalTrackingWindowEditField  matlab.ui.control.NumericEditField
        LocalTrackingWindowEditFieldLabel  matlab.ui.control.Label
        PSD_NoiseTrackingWindowEditField  matlab.ui.control.NumericEditField
        NoiseTrackingWindowEditFieldLabel  matlab.ui.control.Label
        PSD_SmoothingWindowEditField    matlab.ui.control.NumericEditField
        SmoothingWindowEditFieldLabel   matlab.ui.control.Label
        PSD_WindowOverlapEditField      matlab.ui.control.NumericEditField
        WindowOverlapEditFieldLabel     matlab.ui.control.Label
        PSD_SegmentWindowEditField      matlab.ui.control.NumericEditField
        WindowLengthLabel               matlab.ui.control.Label
        PSD_fCutMaxEditField            matlab.ui.control.NumericEditField
        MaximumFrequencyLabel           matlab.ui.control.Label
        PSD_fCutMinEditField            matlab.ui.control.NumericEditField
        MinimumFrequencyLabel_2         matlab.ui.control.Label
        PSDDetectorSettingsLabel        matlab.ui.control.Label
        BSCDTab                         matlab.ui.container.Tab
        DetectionSettingsGrid_4         matlab.ui.container.GridLayout
        BSCD_wEditField                 matlab.ui.control.NumericEditField
        WindowLengthLabel_7             matlab.ui.control.Label
        BSCD_kEditField                 matlab.ui.control.NumericEditField
        WindowLengthLabel_6             matlab.ui.control.Label
        BSCD_LocalTrackingWindowEditField  matlab.ui.control.NumericEditField
        WindowLengthLabel_5             matlab.ui.control.Label
        BSCD_NoiseTrackingWindowEditField  matlab.ui.control.NumericEditField
        WindowLengthLabel_4             matlab.ui.control.Label
        BSCD_SmoothingWindowEditField   matlab.ui.control.NumericEditField
        WindowLengthLabel_3             matlab.ui.control.Label
        BSCD_WindowLengthEditField      matlab.ui.control.NumericEditField
        WindowLengthLabel_2             matlab.ui.control.Label
        BSCD_fCutMaxEditField           matlab.ui.control.NumericEditField
        fCutMaxLabel                    matlab.ui.control.Label
        BSCD_fCutMinEditField           matlab.ui.control.NumericEditField
        MinimumFrequencyLabel           matlab.ui.control.Label
        BSCDDetectorSettingsLabel       matlab.ui.control.Label
        RBDTab                          matlab.ui.container.Tab
        DetectionSettingsGrid_5         matlab.ui.container.GridLayout
        RBD_AmplitudeThresholdEditField  matlab.ui.control.NumericEditField
        fCutMinLabel_10                 matlab.ui.control.Label
        RBD_SmoothingThresholdEditField  matlab.ui.control.NumericEditField
        fCutMinLabel_9                  matlab.ui.control.Label
        RBD_SmoothingWindowEditField    matlab.ui.control.NumericEditField
        fCutMinLabel_8                  matlab.ui.control.Label
        RBD_DynamicScalingEditField     matlab.ui.control.NumericEditField
        fCutMinLabel_7                  matlab.ui.control.Label
        RBD_BayesianEvidenceEditField   matlab.ui.control.NumericEditField
        fCutMinLabel_6                  matlab.ui.control.Label
        RBD_AR_rightEditField           matlab.ui.control.NumericEditField
        fCutMinLabel_5                  matlab.ui.control.Label
        RBD_AR_leftEditField            matlab.ui.control.NumericEditField
        fCutMinLabel_4                  matlab.ui.control.Label
        RBD_wlenEditField               matlab.ui.control.NumericEditField
        fCutMinLabel_3                  matlab.ui.control.Label
        RBD_fCutMaxEditField            matlab.ui.control.NumericEditField
        fCutMinLabel_2                  matlab.ui.control.Label
        RBD_fCutMinEditField            matlab.ui.control.NumericEditField
        fCutMinLabel                    matlab.ui.control.Label
        RBDDetectorSettingsLabel        matlab.ui.control.Label
        POSTTab                         matlab.ui.container.Tab
        DetectionSettingsGrid_3         matlab.ui.container.GridLayout
        POST_MinLabelLengthEditField    matlab.ui.control.NumericEditField
        MinLabelLengthEditFieldLabel    matlab.ui.control.Label
        POST_MaxGapToMergeEditField     matlab.ui.control.NumericEditField
        MaxGapToMergeEditFieldLabel     matlab.ui.control.Label
        PostProcessingLabel_2           matlab.ui.control.Label
        LabelEditSettingsTab            matlab.ui.container.Tab
        LabelEditSettingsGrid           matlab.ui.container.GridLayout
        ClassificationsEditField        matlab.ui.control.EditField
        ClassificationsEditFieldLabel   matlab.ui.control.Label
        ManualLabelLengthEditField_2    matlab.ui.control.NumericEditField
        ManualLabelLengthEditField_2Label  matlab.ui.control.Label
        ReferenceLabelColorDropDown_2   matlab.ui.control.DropDown
        ReferenceLabelColorDropDown_2Label  matlab.ui.control.Label
        LabelColorDropDown_2            matlab.ui.control.DropDown
        LabelColorDropDown_2Label_2     matlab.ui.control.Label
        SpectrogramColormapDropDown_2   matlab.ui.control.DropDown
        SpectrogramColormapDropDown_2Label  matlab.ui.control.Label
        SpectrogramMaximumFrequencyEditField_2  matlab.ui.control.NumericEditField
        SpectrogramMaximumFrequencyEditField_2Label  matlab.ui.control.Label
        SpectrogramMinimumFrequencyEditField_2  matlab.ui.control.NumericEditField
        SpectrogramMinimumFrequencyEditField_2Label  matlab.ui.control.Label
        SpectrogramWindowOverlapEditField_2  matlab.ui.control.NumericEditField
        SpectrogramWindowOverlapEditField_2Label  matlab.ui.control.Label
        SpectrogramWindowSizeEditField_2  matlab.ui.control.NumericEditField
        SpectrogramWindowSizeEditField_2Label  matlab.ui.control.Label
        VideoSyncSettingsTab            matlab.ui.container.Tab
        VideoSyncSettingsGrid           matlab.ui.container.GridLayout
        ForfuturefeaturesLabel          matlab.ui.control.Label
        ExportSettingsTab               matlab.ui.container.Tab
        ExportSettingsGrid              matlab.ui.container.GridLayout
        ForfuturefeaturesLabel_2        matlab.ui.control.Label
        OtherSettingsTab                matlab.ui.container.Tab
        OtherSettingsGrid               matlab.ui.container.GridLayout
        ThemeDropDown                   matlab.ui.control.DropDown
        ThemeDropDownLabel              matlab.ui.control.Label
        ImportSettingsButton            matlab.ui.control.Button
        ExportSettingsButton            matlab.ui.control.Button
        InfoTab                         matlab.ui.container.Tab
        InfoPanel                       matlab.ui.container.Panel
        InfoGrid                        matlab.ui.container.GridLayout
        CTULogo                         matlab.ui.control.Image
        NIMHLogo                        matlab.ui.control.Image
        AuthorEmailHyperlink            matlab.ui.control.Hyperlink
        AppVersion                      matlab.ui.control.Label
        MatlabVersion                   matlab.ui.control.Label
        ApplicationVersionLabel         matlab.ui.control.Label
        MatlabVersionLabel              matlab.ui.control.Label
        OpenSourceCodeButton            matlab.ui.control.Button
        OpenMastersThesisButton         matlab.ui.control.Button
        OpenDocumentationButton         matlab.ui.control.Button
        SupervisorDepartmentLabel       matlab.ui.control.Label
        AuthorDepartmentLabel           matlab.ui.control.Label
        YearLabel                       matlab.ui.control.Label
        AcademicYearLabel               matlab.ui.control.Label
        SupervisorNameLabel             matlab.ui.control.Label
        SupervisorLabel                 matlab.ui.control.Label
        AuthorNameLabel                 matlab.ui.control.Label
        AuthorLabel                     matlab.ui.control.Label
        Text3Label                      matlab.ui.control.Label
        Text2Label                      matlab.ui.control.Label
        Text1Label                      matlab.ui.control.Label
        DescriptionLabel                matlab.ui.control.Label
        NameOfAppLabel                  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end


    properties (Access = public)

        Theme % Description

        % Different Components
        ButtonsMenu
        ButtonsOther
        Grids
        Labels

        StartLine
        EndLine

        InputFiles = struct( ...
            'USV', {}, ...
            'fs', {}, ...
            'Label', {}, ...
            'ReferenceLabel', {}, ...
            'Video', {}, ...
            'USVPath', {},...
            'LabelPath', {},...
            'ReferenceLabelPath', {},...
            'VideoPath', {}...
            );
        CurrentInputFile = struct( ...
            'USV', {}, ...
            'fs', {}, ...
            'Label', {}, ...
            'ReferenceLabel', {}, ...
            'Video', {}, ...
            'USVPath', {},...
            'LabelPath', {},...
            'ReferenceLabelPath', {},...
            'VideoPath', {}...
            );
        CurrentInputFile_AR = struct( ...
            'USV', {}, ...
            'fs', {}, ...
            'Label', {}, ...
            'ReferenceLabel', {}, ...
            'Video', {}, ...
            'USVPath', {},...
            'LabelPath', {},...
            'ReferenceLabelPath', {},...
            'VideoPath', {}...
            );

        BatchFilesInfo = struct('USVFiles',{},'LabelFiles',{},'ReferenceLabelFiles',{},'VideoFiles',{},'numUSVs', {0}, 'numLabels', {0}, 'numReferenceLabels', {0}, 'numVideos', {0});


    end

    properties (Access = public)
        APP_VERSION = "2.0.2025.5.23"
        AppSettings
    end

    methods (Access = private)

        function applyTheme(app, themeStruct)
            app.Theme = themeStruct;
            app.ThemeDropDown.Value = app.AppSettings.Other.ThemeName;            % update dropdown
            fig = app.SqueakPeekStudioUIFigure;
            fig.Color = themeStruct.Background;

            for i = 1:numel(app.ButtonsMenu)
                app.ButtonsMenu(i).BackgroundColor = themeStruct.Background;
                app.ButtonsMenu(i).FontColor = themeStruct.Text;
            end

            for i = 1:numel(app.ButtonsOther)
                btn = app.ButtonsOther(i);
                if isstruct(btn.UserData) && isfield(btn.UserData, "Style") && strcmp(btn.UserData.Style, "Primary")
                    btn.BackgroundColor = themeStruct.Primary;
                    btn.FontColor = themeStruct.Text;
                elseif isstruct(btn.UserData) && isfield(btn.UserData, "Style") && strcmp(btn.UserData.Style, "Accent")
                    btn.BackgroundColor = themeStruct.Accent;
                    btn.FontColor = themeStruct.Text;
                else
                    btn.BackgroundColor = themeStruct.Background;
                    btn.FontColor = themeStruct.Text;
                end

            end


            for i = 1:numel(app.Grids)
                app.Grids(i).BackgroundColor = themeStruct.Background;
            end
            for i = 1:numel(app.Labels)
                app.Labels(i).FontColor = themeStruct.Text;
            end


            app.ButtonsMenuGrid.BackgroundColor = themeStruct.Primary;
        end
        function InitAppPaths(app) %#ok<MANU>
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'src')));
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'themes')));
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'settings')));
        end
        function InitAppSettings(app)
            basePath = pwd;
            examplePath = fullfile(basePath, "data", "example");
            exportPath = fullfile(basePath, "data", "export");

            app.AppSettings = struct( ...
                "DataInput", struct( ...
                "DefaultUSVFieldSingle",              fullfile(examplePath, "single", "USV_Example_Short.wav"), ...
                "DefaultLabelFieldSingle",            fullfile(examplePath, "single", "Detected_Labels_Example.txt"), ...
                "DefaultReferenceLabelFieldSingle",   fullfile(examplePath, "single", "Reference_Labels_Example.txt"), ...
                "DefaultVideoFieldSingle",            fullfile(examplePath, "single", ""), ...
                "BatchMode",                          false, ...
                "DefaultUSVFieldBatch",               fullfile(examplePath, "batch", "USVs"), ...
                "DefaultLabelFieldBatch",             fullfile(examplePath, "batch", "labels"), ...
                "DefaultReferenceLabelFieldBatch",    fullfile(examplePath, "batch", "referenceLabels"), ...
                "DefaultVideoFieldBatch",             fullfile(examplePath, "batch", "") ...
                ),...
                "Visualization", struct( ...
                "ShowLoadingDialog", false, ...
                "SegmentStartSeconds", 0, ...
                "SegmentLengthSeconds", 1, ...
                "SpectrogramWindow", 1024, ...
                "SpectrogramOverlap", 512, ...
                "SpectrogramMinFrequency", 40, ...
                "SpectrogramMaxFrequency", 120, ...
                "SpectrogramColormap","parula", ...
                "ShowReferenceLabels", true, ...
                "ShowLabels", true, ...
                "ReferenceLabelColor", "white", ...
                "LabelColor", "cyan", ...
                "ManualLabelLength", 0.075, ...
                "SonificationST", -35, ...
                "SonificationSlowdown", 4 ...
                ), ...
                "LabelEdit", struct( ...
                "SpectrogramWindow", 1024, ...
                "SpectrogramOverlap", 512, ...
                "SpectrogramMinFrequency", 40, ...
                "SpectrogramMaxFrequency", 120, ...
                "SpectrogramColormap","hsv", ...
                "Classifications", "d,sk,5,5t,5w,c5" ...
                ), ...
                "VideoSync", struct( ...
                "VideoSyncPath",fullfile(examplePath, "single", "LPS-SI2homo-mH02-I04.mp4"), ...
                "SyncTime",68, ...
                "AudioType", "Original From Video", ...
                "ExportPath", fullfile(exportPath, "video") ...
                ), ...
                "Detection", struct( ...
                "ExportPath", exportPath, ...
                "PSD", struct( ...
                "fcutMin", 40000, ...
                "fcutMax", 120000, ...
                "ROIstart", 60, ...
                "ROIlength", 10, ...
                "runWholeSignal", true, ...
                "segmentLength", 8192, ...
                "overlapFactor", 0.59, ...
                "maWindow", 3, ...
                "noiseWindow", 240, ...
                "localWindow", 194, ...
                "k", 0.023, ...
                "w", 0.994, ...
                "minEffectivePower", 0.000085 ...
                ),...
                "BSCD", struct( ...
                "fcutMin", 40000, ...
                "fcutMax", 120000, ...
                "wlen", 0.01, ...
                "maWindow", 5000, ...
                "noiseWindow", 256, ...
                "localWindow", 256, ...
                "k", 0.023, ...
                "w", 0.994 ...
                ),...
                "RBD", struct( ...
                "fcutMin", 40000, ...
                "fcutMax", 120000, ...
                "wlen", 0.04, ...
                "AR_order_left", 4, ...
                "AR_order_right", 4, ...
                "Bayesian_Evidence_order", 4, ...
                "dynamicScaling", 0.3, ...
                "smoothingWindowRBD", 0.02, ...
                "smoothingWindowThr", 0.02, ...
                "amplitudeThreshold", 0.02 ...
                ),...
                "POST", struct( ...
                "maxGapToMerge", 0.005,...
                "minLabelLength", 0.001...
                )...
                ), ...
                "Export", struct("ExportPath", ""), ...
                "Other", struct("ThemeName", "Light","DefaultSettingsFile", "default.json") ...
                );
        end
        function InitComponents(app)
            % Move top menu to correct position automatically
            app.ButtonsMenuPanel.Position = [1,650,1000,50];
            app.SettingsMenuPanel.Position = [12,615,973,25];
            % Initialize Buttons for switching with Button Menu
            app.DataInputButton.UserData = app.DataInputTab;
            app.VisualizationButton.UserData = app.VisualizationTab;
            app.DetectionButton.UserData = app.DetectionTab;
            app.LabelEditButton.UserData = app.LabelEditTab;
            app.VideoSyncButton.UserData = app.VideoSyncTab;
            app.MetricsButton.UserData = app.MetricsTab;
            app.SettingsButton.UserData = app.SettingsTab;
            app.InfoButton.UserData = app.InfoTab;

            % UIAxis
            app.SpectrogramUIAxes.Interactions = [ ...
                dataTipInteraction; ...
                panInteraction('Dimensions','x'); ...  % only horizontal
                zoomInteraction('Dimensions','x')];    % only horizontal
            app.WaveformUIAxes.Interactions = [ ...
                dataTipInteraction; ...
                panInteraction('Dimensions','x'); ...  % only horizontal
                zoomInteraction('Dimensions','x')];    % only horizontal


            addlistener(app.SpectrogramUIAxes, 'XLim', 'PostSet', ...
                @(~, evt)app.onVisualisationAxesMoved(evt.AffectedObject));
            addlistener(app.WaveformUIAxes, 'XLim', 'PostSet', ...
                @(~, evt)app.onVisualisationAxesMoved(evt.AffectedObject));
            % define component groups for theming
            app.ButtonsMenu = [app.DataInputButton, app.VisualizationButton, app.DetectionButton, ...
                app.LabelEditButton, app.VideoSyncButton, app.MetricsButton, ...
                app.SettingsButton, app.InfoButton];

            allButtons = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uibutton');
            app.ButtonsOther = setdiff(allButtons, app.ButtonsMenu);

            app.Grids = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uigridlayout');
            app.Labels = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uilabel');
        end

        function SetThemeRules(app) % currently only for buttons
            app.LoadFilesButton.UserData.Style = "Accent";
            app.SonifyButton.UserData.Style = "Accent";
            app.RunDetectorsButton.UserData.Style = "Accent";
            app.ReloadMetricsButton.UserData.Style = "Accent";
            app.ExportSettingsButton.UserData.Style = "Accent";
            app.OpenDocumentationButton.UserData.Style = "Primary";
            app.OpenSourceCodeButton.UserData.Style = "Primary";
            app.OpenMastersThesisButton.UserData.Style = "Accent";
            app.SynchronizeVideoButton.UserData.Style = "Accent";
            app.ExportLabelsButton.UserData.Style = "Accent";

            app.AcceptDetectionButton.UserData.Style = "Primary";
            app.AcceptClassificationButton.UserData.Style = "Primary";
            app.RejectDetectionButton.UserData.Style = "Primary";
            app.RejectClassificationButton.UserData.Style = "Primary";
        end

        function ApplyAllAppSettings(app)
            % OTHER
            %THEME
            themeName = app.AppSettings.Other.ThemeName;
            applyTheme(app, ThemeColors.get(themeName));

            % INPUT
            % SINGLE PATHS
            app.SelectUSVFieldSingle.Value = app.AppSettings.DataInput.DefaultUSVFieldSingle;
            app.SelectLabelFieldSingle.Value = app.AppSettings.DataInput.DefaultLabelFieldSingle;
            app.SelectReferenceLabelFieldSingle.Value = app.AppSettings.DataInput.DefaultReferenceLabelFieldSingle;

            app.SelectVideoFieldSingle.Value = app.AppSettings.DataInput.DefaultVideoFieldSingle;
            % BATCH PATHS
            app.SelectUSVFieldBatch.Value = app.AppSettings.DataInput.DefaultUSVFieldBatch;
            app.SelectLabelFieldBatch.Value = app.AppSettings.DataInput.DefaultLabelFieldBatch;
            app.SelectReferenceLabelFieldBatch.Value = app.AppSettings.DataInput.DefaultReferenceLabelFieldBatch;
            app.SelectVideoFieldBatch.Value = app.AppSettings.DataInput.DefaultVideoFieldBatch;
            % update file count
            % --- Check if folders exist before counting
            app.checkFileCount("USVs");
            app.checkFileCount("Labels");
            app.checkFileCount("ReferenceLabels");
            app.checkFileCount("Videos");

            % SINGLE / BATCH
            btn = app.SingleBatchModeButton;

            if app.AppSettings.DataInput.BatchMode == true % single
                btn.Text = 'Batch Mode';
                btn.BackgroundColor = app.Theme.Accent;

                app.SingleModePanel.Enable = "off";
                app.SingleModePanel.Visible = "off";
                app.BatchModePanel.Enable = "on";
                app.BatchModePanel.Visible = "off";
            else
                btn.Text = 'Single Mode';
                btn.BackgroundColor = app.Theme.Background;
                app.SingleModePanel.Enable = "on";
                app.SingleModePanel.Visible = "on";
                app.BatchModePanel.Enable = "off";
                app.BatchModePanel.Visible = "off";
            end


            % VISUALISATION
            app.SegmentStartsEditField.Value = app.AppSettings.Visualization.SegmentStartSeconds;
            app.SegmentLengthsEditField.Value = app.AppSettings.Visualization.SegmentLengthSeconds;
            app.ShowLoadingDialogueCheckBox.Value = app.AppSettings.Visualization.ShowLoadingDialog;

            app.SpectrogramWindowSizeEditField.Value = app.AppSettings.Visualization.SpectrogramWindow;
            app.SpectrogramWindowOverlapEditField.Value = app.AppSettings.Visualization.SpectrogramOverlap;
            app.SpectrogramMinimumFrequencyEditField.Value = app.AppSettings.Visualization.SpectrogramMinFrequency;
            app.SpectrogramMaximumFrequencyEditField.Value = app.AppSettings.Visualization.SpectrogramMaxFrequency;

            app.SpectrogramColormapDropDown.Value = app.AppSettings.Visualization.SpectrogramColormap;
            app.ShowLabelsCheckBox.Value = app.AppSettings.Visualization.ShowLabels;
            app.ShowReferenceLabelsCheckBox.Value = app.AppSettings.Visualization.ShowReferenceLabels;

            app.LabelColorDropDown.Value = app.AppSettings.Visualization.LabelColor;
            app.ReferenceLabelColorDropDown.Value = app.AppSettings.Visualization.ReferenceLabelColor;

            app.SonificationSTEditField.Value = app.AppSettings.Visualization.SonificationST;
            app.SonificationSlowdownEditField.Value = app.AppSettings.Visualization.SonificationSlowdown;

            app.ManualLabelLengthEditField.Value = app.AppSettings.Visualization.ManualLabelLength;
            % DETECTION
            app.SelectDetectorsExportFolderEditField.Value = app.AppSettings.Detection.ExportPath;
            % DETECTION - PSD
            app.PSD_fCutMinEditField.Value = app.AppSettings.Detection.PSD.fcutMin;
            app.PSD_fCutMaxEditField.Value = app.AppSettings.Detection.PSD.fcutMax;
            app.PSD_SegmentWindowEditField.Value = app.AppSettings.Detection.PSD.segmentLength;
            app.PSD_WindowOverlapEditField.Value = app.AppSettings.Detection.PSD.overlapFactor;
            app.PSD_SmoothingWindowEditField.Value = app.AppSettings.Detection.PSD.maWindow;
            app.PSD_NoiseTrackingWindowEditField.Value = app.AppSettings.Detection.PSD.noiseWindow;
            app.PSD_LocalTrackingWindowEditField.Value = app.AppSettings.Detection.PSD.localWindow;
            app.PSD_kEditField.Value = app.AppSettings.Detection.PSD.k;
            app.PSD_wEditField.Value = app.AppSettings.Detection.PSD.w;
            app.PSD_MinPowerEditField.Value = app.AppSettings.Detection.PSD.minEffectivePower;
            % DETECTION - BSCD
            app.BSCD_fCutMinEditField.Value = app.AppSettings.Detection.BSCD.fcutMin;
            app.BSCD_fCutMaxEditField.Value = app.AppSettings.Detection.BSCD.fcutMax;
            app.BSCD_WindowLengthEditField.Value = app.AppSettings.Detection.BSCD.wlen;
            app.BSCD_SmoothingWindowEditField.Value = app.AppSettings.Detection.BSCD.maWindow;
            app.BSCD_NoiseTrackingWindowEditField.Value = app.AppSettings.Detection.BSCD.noiseWindow;
            app.BSCD_LocalTrackingWindowEditField.Value = app.AppSettings.Detection.BSCD.localWindow;
            app.BSCD_kEditField.Value = app.AppSettings.Detection.BSCD.k;
            app.BSCD_wEditField.Value = app.AppSettings.Detection.BSCD.w;
            % DETECTION - RBD
            app.RBD_fCutMinEditField.Value = app.AppSettings.Detection.RBD.fcutMin;
            app.RBD_fCutMaxEditField.Value = app.AppSettings.Detection.RBD.fcutMax;
            app.RBD_wlenEditField.Value = app.AppSettings.Detection.RBD.wlen;
            app.RBD_AR_leftEditField.Value = app.AppSettings.Detection.RBD.AR_order_left;
            app.RBD_AR_rightEditField.Value = app.AppSettings.Detection.RBD.AR_order_right;
            app.RBD_BayesianEvidenceEditField.Value = app.AppSettings.Detection.RBD.Bayesian_Evidence_order;
            app.RBD_DynamicScalingEditField.Value = app.AppSettings.Detection.RBD.dynamicScaling;
            app.RBD_SmoothingWindowEditField.Value = app.AppSettings.Detection.RBD.smoothingWindowRBD;
            app.RBD_SmoothingThresholdEditField.Value = app.AppSettings.Detection.RBD.smoothingWindowThr;
            app.RBD_AmplitudeThresholdEditField.Value = app.AppSettings.Detection.RBD.amplitudeThreshold;
            % DETECTION - POSTPROCESSING
            app.POST_MaxGapToMergeEditField.Value = app.AppSettings.Detection.POST.maxGapToMerge;
            app.POST_MinLabelLengthEditField.Value = app.AppSettings.Detection.POST.minLabelLength;
            % LABEL EDIT
            disableDefaultInteractivity(app.LabelEditSpectrogramUIAxes);
            app.ManualLabelLengthEditField_2.Value = app.AppSettings.Visualization.ManualLabelLength;

            app.ClassificationsEditField.Value = app.AppSettings.LabelEdit.Classifications;
            app.ClassificationsEditFieldValueChanged();

            % VIDEO
            app.SelectVideoFieldSingle_2.Value = app.AppSettings.VideoSync.VideoSyncPath;
            app.SynchronizationTimeinVideoEditField.Value = app.AppSettings.VideoSync.SyncTime;
            app.AudioOutputDropDown.Value = app.AppSettings.VideoSync.AudioType;
        end

        function loadSettingsFromFile(app, filepath)
            if nargin < 2
                [file, path] = uigetfile('*.json', 'Load Settings');
                if isequal(file, 0)
                    return; % User canceled
                end
                filepath = fullfile(path, file);
            end

            fid = fopen(filepath, 'r');
            if fid == -1
                uialert(app.SqueakPeekStudioUIFigure, 'Could not open file for reading.', 'File Error');
                return;
            end

            raw = fread(fid, '*char')';
            fclose(fid);

            try
                settings = jsondecode(raw);
                app.AppSettings = settings;
                ApplyAllAppSettings(app);  % Re-apply after loading
            catch ME
                uialert(app.SqueakPeekStudioUIFigure, sprintf('Error reading settings:\n%s', ME.message), 'JSON Error');
            end
        end

        function loadDefaultSettingsIfExists(app)
            preloadName = app.AppSettings.Other.DefaultSettingsFile;
            appFolder = fileparts(mfilename('fullpath'));
            settingsPath = fullfile(appFolder, 'settings', preloadName);

            if exist(settingsPath, 'file') == 2
                try
                    loadSettingsFromFile(app, settingsPath);
                    disp("Loaded default settings from: " + settingsPath);
                catch err
                    warning('%s: %s', err.identifier, err.message);
                end
            else
                %warning("Default settings file not found at: %s", settingsPath);
            end
        end


        function [files, numFiles] = getFilesFromFolder(~,folderPath, fileExtension)
            % getFilesFromFolder - Lists files with the given extension in a folder.
            %
            % Inputs:
            %   folderPath     - string or char, path to the folder
            %   fileExtension  - string or char, e.g. '*.wav', '*.txt', '*.mov'
            %
            % Outputs:
            %   files     - struct array from dir()
            %   numFiles  - number of files found

            if nargin < 2
                error('You must provide both folderPath and fileExtension, e.g., ''*.wav''');
            end

            files = dir(fullfile(folderPath, fileExtension));
            numFiles = numel(files);

            %fprintf("Found %d %s files in:\n%s\n", numFiles, fileExtension, folderPath);
            for i = 1:numFiles
                fullPath = fullfile(files(i).folder, files(i).name);
                %   fprintf("%s\n", fullPath);
            end
        end

        function count = checkFileCount(app, type)
            try
                switch type
                    case "USVs"
                        [files, count] = getFilesFromFolder(app, app.SelectUSVFieldBatch.Value, '*.wav');
                        app.BatchFilesInfo(1).USVFiles = files;
                        app.BatchFilesInfo(1).numUSVs = count;
                        app.USVBatchFound.Text = sprintf("(%d)", count);
                        app.SelectUSVDropDown.Items = {files.name};
                    case "Labels"
                        [files, count] = getFilesFromFolder(app, app.SelectLabelFieldBatch.Value, '*.txt');
                        app.BatchFilesInfo(1).LabelFiles = files;
                        app.BatchFilesInfo(1).numLabels = count;
                        app.LabelBatchFound.Text = sprintf("(%d)", count);
                        app.SelectDetectedLabelDropDown.Items = {files.name};
                    case "ReferenceLabels"
                        [files, count] = getFilesFromFolder(app, app.SelectReferenceLabelFieldBatch.Value, '*.txt');
                        app.BatchFilesInfo(1).ReferenceLabelFiles = files;
                        app.BatchFilesInfo(1).numReferenceLabels = count;
                        app.ReferenceLabelBatchFound.Text = sprintf("(%d)", count);
                        app.SelectReferenceLabelDropDown.Items = {files.name};
                    case "Videos"
                        [files, count] = getFilesFromFolder(app, app.SelectVideoFieldBatch.Value, '*.mov');
                        app.BatchFilesInfo(1).VideoFiles = files;
                        app.BatchFilesInfo(1).numVideos = count;
                        app.VideoBatchFound.Text = sprintf("(%d)", count);
                    otherwise
                        warning("Wrong input for function checkFileCount");
                        count = 0;
                end
            catch ME
                warning("Error in checkFileCount for %s: %s", type, ME.message);
                count = 0;
            end
        end

        function labelEditUpdateStartTime(app, line)
            % Only allow X to change
            newX = mean(line.Position(:, 1));  % get center X of line
            fixedY = [40; 120];                % keep original Y-range

            % Reset line to be perfectly vertical
            line.Position = [newX * ones(2, 1), fixedY];

            % Update label
            i = app.LabelEditCurrentLabel.Value;
            app.CurrentInputFile_AR.Label(i).StartTime = newX;

            %disp("Updated StartTime to: " + newX);
            app.LoadLabelEditButtonPushed();
        end

        function labelEditUpdateEndTime(app, line)
            % Only allow X to change
            newX = mean(line.Position(:, 1));  % get center X of line
            fixedY = [40; 120];                % keep original Y-range

            % Reset line to be perfectly vertical
            line.Position = [newX * ones(2, 1), fixedY];

            % Update label
            i = app.LabelEditCurrentLabel.Value;
            app.CurrentInputFile_AR.Label(i).EndTime = newX;

            %disp("Updated EndTime to: " + newX);
            app.LoadLabelEditButtonPushed();
        end

        function onVisualisationAxesMoved(app, movedAxes)
            newLimits = movedAxes.XLim;

            % Synchronize both axes
            app.SpectrogramUIAxes.XLim = newLimits;
            app.WaveformUIAxes.XLim = newLimits;

            % Update fields
            app.SegmentStartsEditField.Value = newLimits(1);
            app.SegmentLengthsEditField.Value = newLimits(2) - newLimits(1);

            % Re-render
            PlotButtonPushed(app);
        end

        function ShowClassificationEdit(app)

            % === Sync dropdown if edit‐field text matches an item ===
            editText = app.EditLabelClassificationEditField.Value;     % char or string
            items    = app.ClassificationDropdown.Items;               % cellstr or string array

            % Normalize both to cellstr of char vectors for strcmp
            if isstring(items)
                itemsCell = cellstr(items);
            else
                itemsCell = items;
            end
            editTextChar = char(editText);

            % Find first match
            matchIdx = find(strcmp(itemsCell, editTextChar), 1);
            if ~isempty(matchIdx)
                app.ClassificationDropdown.Value = itemsCell{matchIdx};
            end

            % === Show the panel ===
            app.EditLabelClassificationPanel.Visible = 'on';
        end
        function signal_out = sonifySegment(...
                app, audioFile, startTime, endTime, fs, ST, slowdown, targetFs, winLen, winHop)
            % sonifySegment  Pitch-shift & time-stretch a segment of audio.
            %
            % Inputs:
            %   audioFile  – vector of raw audio samples
            %   startTime  – segment start (seconds)
            %   endTime    – segment end   (seconds)
            %   fs         – sample rate of audioFile
            %   ST         – pitch shift in semitones (e.g. –36)
            %   slowdown   – time stretch factor (e.g. 4)
            %   targetFs   – playback sample rate (e.g. 44100)
            %   winLen     – window length (samples, e.g. 1024)
            %   winHop     – window hop   (samples, e.g. winLen/4)
            %
            % Output:
            %   signal_out – sonified, stretched audio ready for playback

            % === Extract current segment ===
            startIdx = max(1, round(startTime * fs) + 1);
            endIdx   = min(length(audioFile), round(endTime * fs));
            x        = audioFile(startIdx:endIdx);

            % === Bandpass filter (40–120 kHz) ===
            bpFilt = designfilt('bandpassiir', ...
                'FilterOrder',12, ...
                'HalfPowerFrequency1',40000, ...
                'HalfPowerFrequency2',120000, ...
                'SampleRate',fs);
            x = filtfilt(bpFilt, x);

            % === Phase vocoder analysis ===
            N      = length(x);
            nFrames= floor((N - winLen)/winHop) + 1;
            win    = hamming(winLen);
            X      = zeros(winLen, nFrames);
            for k = 1:nFrames
                idx       = (k-1)*winHop + (1:winLen);
                frame     = x(idx) .* win;
                X(:,k)    = fft(frame);
            end

            % === Pitch shift ===
            R_pitch = 2^(ST/12);
            tOrig   = (0:nFrames-1)';
            tNew    = (0:1/R_pitch:(nFrames-1))';
            Xmag    = interp1(tOrig, abs(X').^1, tNew, 'linear', 'extrap')';
            phase   = angle(X);
            dPhase  = diff(phase,1,2);
            dPint   = interp1(tOrig(2:end), dPhase.', tNew(2:end), 'linear', 'extrap').';
            phaseN  = [phase(:,1), repmat(phase(:,1),1,size(dPint,2)) + cumsum(dPint,2)];
            Yp      = Xmag .* exp(1j*phaseN);

            % === Overlap-add synthesis after pitch shift ===
            nFp      = size(Yp,2);
            yPitch   = zeros((nFp-1)*winHop + winLen,1);
            for k = 1:nFp
                idx     = (k-1)*winHop + (1:winLen);
                seg     = real(ifft(Yp(:,k))) .* win;
                yPitch(idx) = yPitch(idx) + seg;
            end

            % === Time stretch ===
            % repeat analysis on yPitch
            N2       = length(yPitch);
            nFr2     = floor((N2 - winLen)/winHop) + 1;
            X2       = zeros(winLen, nFr2);
            for k = 1:nFr2
                idx   = (k-1)*winHop + (1:winLen);
                X2(:,k)= fft(yPitch(idx) .* win);
            end

            tOrig2   = (0:nFr2-1)';
            tNew2    = (0:1/slowdown:(nFr2-1))';
            Xmag2    = interp1(tOrig2, abs(X2').^1, tNew2, 'linear', 'extrap')';
            phase2   = angle(X2);
            dPhase2  = diff(phase2,1,2);
            dPint2   = interp1(tOrig2(2:end), dPhase2.', tNew2(2:end), 'linear', 'extrap').';
            phaseN2  = [phase2(:,1), repmat(phase2(:,1),1,size(dPint2,2)) + cumsum(dPint2,2)];
            Y2       = Xmag2 .* exp(1j*phaseN2);

            % === Overlap-add synthesis after stretch ===
            nFn      = size(Y2,2);
            yOut     = zeros((nFn-1)*winHop + winLen,1);
            for k = 1:nFn
                idx   = (k-1)*winHop + (1:winLen);
                seg   = real(ifft(Y2(:,k))) .* win;
                yOut(idx) = yOut(idx) + seg;
            end

            % === Normalize output ===
            signal_out = yOut / max(abs(yOut) + eps);
            %signal_out_r = resample(signal_out, targetFs, fs);
            %disp(signal_out_r)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            disp("Welcome to Squeak Peek Studio by Antonín Gazda");
            fprintf("Version %s\n",app.APP_VERSION);
            app.AppVersion.Text = app.APP_VERSION;
            InitAppPaths(app);
            InitAppSettings(app);
            loadDefaultSettingsIfExists(app);

            InitComponents(app);  % define Components
            SetThemeRules(app);   % set Primary,Accent,Background etc.
            ApplyAllAppSettings(app);

            % Select default tab
            app.HandleMenuButtons(struct("Source", app.DataInputButton));
            % Keyboard shortcuts
            app.SqueakPeekStudioUIFigure.WindowKeyPressFcn = createCallbackFcn(app,@SqueakPeekStudioUIFigureKeyPress,true);

            app.LoadFilesButtonPushed();

        end

        % Button pushed function: DataInputButton, DetectionButton, 
        % ...and 6 other components
        function HandleMenuButtons(app, event)
            clickedButton = event.Source;
            % Reset all buttons to the inactive color

            for b = app.ButtonsMenu
                b.BackgroundColor = app.Theme.Background;
            end

            % Highlight the clicked button
            clickedButton.BackgroundColor = app.Theme.Accent;

            if clickedButton == app.SettingsButton && ...
                    app.TabGroup.SelectedTab ~= app.SettingsTab && ...
                    app.TabGroup.SelectedTab ~= app.InfoTab

                lastTab = app.TabGroup.SelectedTab;

                %disp("Went into settings");
                %fprintf("lastTab = %s\n", lastTab.Title);
                app.SettingsDropDown.Value = lastTab.Title;
                SettingsDropDownValueChanged(app, struct('Value', lastTab.Title));

            end

            % Switch to the tab associated with this button
            app.TabGroup.SelectedTab = clickedButton.UserData;
        end

        % Button pushed function: SingleBatchModeButton, 
        % ...and 1 other component
        function SingleBatchModeButtonPushed(app, event)
            btn = event.Source;
            lastState = app.AppSettings.DataInput.BatchMode;
            if lastState == false % single
                app.checkFileCount("USVs");
                app.checkFileCount("Labels");
                app.checkFileCount("ReferenceLabels");
                app.checkFileCount("Videos");

                % Switch to Batch mode
                btn.Text = 'Batch Mode';
                btn.BackgroundColor = app.Theme.Accent;
                app.AppSettings.DataInput.BatchMode = 1;

                %change text on labels and the files they accept
                app.SingleModePanel.Enable = "off";
                app.SingleModePanel.Visible = "off";

                app.BatchModePanel.Enable = "on";
                app.BatchModePanel.Visible = "on";

            else
                % Switch to Single mode
                btn.Text = 'Single Mode';
                btn.BackgroundColor = app.Theme.Background;
                app.AppSettings.DataInput.BatchMode = 0;
                app.SingleModePanel.Enable = "on";
                app.SingleModePanel.Visible = "on";
                app.BatchModePanel.Enable = "off";
                app.BatchModePanel.Visible = "off";
            end
        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            app.InputFiles = struct( ...
                'USV', {}, ...
                'fs', {}, ...
                'Label', {}, ...
                'ReferenceLabel', {}, ...
                'Video', {}, ...
                'USVPath', {}, ...
                'LabelPath', {}, ...
                'ReferenceLabelPath', {}, ...
                'VideoPath', {} ...
                );

            d = uiprogressdlg(app.SqueakPeekStudioUIFigure, ...
                'Title', 'Please Wait', ...
                'Message', 'Loading files...', ...
                'Indeterminate', 'on');
            drawnow;

            try
                if app.AppSettings.DataInput.BatchMode == 0  % Single mode
                    usvPath = app.SelectUSVFieldSingle.Value;
                    labelPath = app.SelectLabelFieldSingle.Value;
                    refLabelPath = app.SelectReferenceLabelFieldSingle.Value;
                    videoPath = app.SelectVideoFieldSingle.Value;

                    if exist(usvPath, 'file')
                        [usv, fs] = audioread(usvPath);
                    else
                        error('USV file not found:\n%s', usvPath);
                    end

                    if exist(labelPath, 'file')
                        label = importLabels(labelPath, fs);
                    else
                        label = [];
                    end

                    if exist(refLabelPath, 'file')
                        refLabel = importLabels(refLabelPath, fs);
                    else
                        refLabel = [];
                    end

                    video = [];  % Video not loaded yet

                    app.InputFiles(1) = struct( ...
                        'USV', usv, ...
                        'fs', fs, ...
                        'Label', label, ...
                        'ReferenceLabel', refLabel, ...
                        'Video', video, ...
                        'USVPath', usvPath, ...
                        'LabelPath', labelPath, ...
                        'ReferenceLabelPath', refLabelPath, ...
                        'VideoPath', videoPath ...
                        );

                    app.CurrentInputFile = app.InputFiles(1);

                else  % Batch mode
                    app.checkFileCount("USVs");
                    app.checkFileCount("Labels");
                    app.checkFileCount("ReferenceLabels");
                    app.checkFileCount("Videos");

                    usvName = app.SelectUSVDropDown.Value;
                    labelName = app.SelectDetectedLabelDropDown.Value;
                    refLabelName = app.SelectReferenceLabelDropDown.Value;

                    usvPath = fullfile(app.SelectUSVFieldBatch.Value, usvName);
                    labelPath = fullfile(app.SelectLabelFieldBatch.Value, labelName);
                    refLabelPath = fullfile(app.SelectReferenceLabelFieldBatch.Value, refLabelName);
                    videoPath = "";  % Not used currently

                    if exist(usvPath, 'file')
                        [usv, fs] = audioread(usvPath);
                    else
                        error('USV file not found:\n%s', usvPath);
                    end

                    if exist(labelPath, 'file')
                        label = importLabels(labelPath, fs);
                    else
                        label = [];
                    end

                    if exist(refLabelPath, 'file')
                        refLabel = importLabels(refLabelPath, fs);
                    else
                        refLabel = [];
                    end

                    newStruct = struct( ...
                        'USV', usv, ...
                        'fs', fs, ...
                        'Label', label, ...
                        'ReferenceLabel', refLabel, ...
                        'Video', [], ...
                        'USVPath', usvPath, ...
                        'LabelPath', labelPath, ...
                        'ReferenceLabelPath', refLabelPath, ...
                        'VideoPath', videoPath ...
                        );
                    app.InputFiles(1) = newStruct;
                    app.CurrentInputFile = app.InputFiles(1);
                end

                app.CurrentInputFile_AR = app.CurrentInputFile;
                d.Message = 'Finished loading files.';
                pause(0.4);

            catch ME
                if isvalid(d)
                    close(d);
                end
                uialert(app.SqueakPeekStudioUIFigure, ...
                    sprintf("Error loading files:\n\n%s", ME.message), ...
                    "Load Error", ...
                    "Icon", "error");
                return;
            end

            if isvalid(d)
                close(d);
            end

            app.HandleMenuButtons(struct('Source', app.VisualizationButton));
            app.PlotButtonPushed();
            app.ReloadMetricsButtonPushed();
            app.LoadLabelEditButtonPushed();

        end

        % Button pushed function: OpenDocumentationButton
        function OpenDocumentationButtonPushed(app, event)
            web('http://www.antoningazda.com/squeakpeek/docs', '-browser')
        end

        % Button pushed function: OpenMastersThesisButton
        function OpenMastersThesisButtonPushed(app, event)
            web('http://www.antoningazda.com/squeakpeek/thesis', '-browser')
        end

        % Button pushed function: OpenSourceCodeButton
        function OpenSourceCodeButtonPushed(app, event)
            web('http://www.antoningazda.com/squeakpeek/source', '-browser')
        end

        % Value changed function: SettingsDropDown
        function SettingsDropDownValueChanged(app, event)
            %fprintf("Changed to %s",event.Value);
            switch event.Value
                case "Data Input"
                    app.SettingsInsideTab.SelectedTab = app.DataInputSettingsTab;
                case "Visualization"
                    app.SettingsInsideTab.SelectedTab = app.VisualizationSettingsTab;
                case "Detection"
                    app.SettingsInsideTab.SelectedTab = app.DetectionSettingsTab;
                case "Label Edit"
                    app.SettingsInsideTab.SelectedTab = app.LabelEditSettingsTab;
                case "Video Sync"
                    app.SettingsInsideTab.SelectedTab = app.VideoSyncSettingsTab;
                case "Metrics"
                    app.SettingsInsideTab.SelectedTab = app.ExportSettingsTab;
                case "Other"
                    app.SettingsInsideTab.SelectedTab = app.OtherSettingsTab;
                otherwise
                    warning("Unknown dropdown selection: %s", event.Value);
            end
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            themeName = app.ThemeDropDown.Value;
            app.AppSettings.Other.ThemeName = themeName;
            applyTheme(app,ThemeColors.get(themeName));
        end

        % Button pushed function: ExportSettingsButton
        function ExportSettingsButtonPushed(app, event)
            saveSettingsToFile(app);
        end

        % Button pushed function: ImportSettingsButton
        function ImportSettingsButtonPushed(app, event)
            loadSettingsFromFile(app);
        end

        % Button pushed function: SelectUSVButtonSingle
        function SelectUSVButtonSinglePushed(app, event)
            [tempFile, tempPath] = uigetfile({'*.wav', 'USV Vocalization'}, 'Select USV Vocalization');
            if ~isequal(tempFile, 0)
                app.SelectUSVFieldSingle.Value = fullfile(tempPath, tempFile);
            end
        end

        % Button pushed function: SelectLabelButtonSingle
        function SelectLabelButtonSinglePushed(app, event)
            [tempFile, tempPath] = uigetfile({'*.txt', 'Labels'}, 'Select Labels');
            if ~isequal(tempFile, 0)
                app.SelectLabelFieldSingle.Value = fullfile(tempPath, tempFile);
            end
        end

        % Button pushed function: SelectReferenceLabelButtonSingle
        function SelectReferenceLabelButtonSinglePushed(app, event)
            [tempFile, tempPath] = uigetfile({'*.txt', 'Reference Labels'}, 'Select Reference Labels');
            if ~isequal(tempFile, 0)
                app.SelectReferenceLabelFieldSingle.Value = fullfile(tempPath, tempFile);
            end
        end

        % Callback function: SelectVideoButtonSingle, 
        % ...and 1 other component
        function SelectVideoButtonSinglePushed(app, event)
            [tempFile, tempPath] = uigetfile({'*.mov', 'Video'}, 'Select Video');
            if ~isequal(tempFile, 0)
                app.SelectVideoFieldSingle.Value = fullfile(tempPath, tempFile);
            end
        end

        % Button pushed function: SelectUSVButtonBatch
        function SelectUSVButtonBatchPushed(app, event)
            tempPath = uigetdir('', 'Select USVs Folder');
            if ~isequal(tempPath, 0)
                app.SelectUSVFieldBatch.Value = tempPath;
            end
            app.checkFileCount("USVs");
        end

        % Callback function
        function BREAKButtonPushed(app, event)
            a = 0;
            b = a;
        end

        % Button pushed function: SelectLabeltxtButtonBatch
        function SelectLabeltxtButtonBatchPushed(app, event)
            tempPath = uigetdir('', 'Select Labels Folder');
            if ~isequal(tempPath, 0)
                app.SelectLabelFieldBatch.Value = tempPath;
            end
            app.checkFileCount("Labels");
        end

        % Button pushed function: SelectReferenceLabeltxtButtonBatch
        function SelectReferenceLabeltxtButtonBatchPushed(app, event)
            tempPath = uigetdir('', 'Select Reference Labels Folder');
            if ~isequal(tempPath, 0)
                app.SelectReferenceLabelFieldBatch.Value = tempPath;
            end
            app.checkFileCount("ReferenceLabels");
        end

        % Button pushed function: SelectVideomp4ButtonBatch
        function SelectVideomp4ButtonBatchPushed(app, event)
            tempPath = uigetdir('', 'Select Videos Folder');
            if ~isequal(tempPath, 0)
                app.SelectVideoFieldBatch.Value = tempPath;
            end
            app.checkFileCount("Videos");
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % --- Show loading dialog
            if app.AppSettings.Visualization.ShowLoadingDialog
                d = uiprogressdlg(app.SqueakPeekStudioUIFigure, ...
                    'Title', 'Please Wait', ...
                    'Message', 'Rendering waveform and spectrogram...', ...
                    'Indeterminate', 'on');
                drawnow;
            end

            try
                % --- Extract inputs
                USVVariable = app.CurrentInputFile_AR.USV;
                labelStruct = app.CurrentInputFile_AR.Label;
                referenceLabelStruct = app.CurrentInputFile_AR.ReferenceLabel;
                fs = app.CurrentInputFile_AR.fs;

                % --- Segment timing
                segmentStartSeconds = app.SegmentStartsEditField.Value;
                segmentLengthSeconds = app.SegmentLengthsEditField.Value;
                segmentEndSeconds = segmentStartSeconds+segmentLengthSeconds;

                % --- Index range
                segmentStartIndex = round(segmentStartSeconds * fs) + 1;
                segmentEndIndex   = round((segmentStartSeconds + segmentLengthSeconds) * fs);

                % --- Extract segment and generate time vector
                USVSegment = USVVariable(segmentStartIndex:segmentEndIndex);
                segmentTime = linspace(segmentStartSeconds, ...
                    segmentStartSeconds + segmentLengthSeconds, ...
                    length(USVSegment));

                [~, name, ~] = fileparts(app.CurrentInputFile_AR.USVPath);
                % === Plot waveform ===
                plot(app.WaveformUIAxes, segmentTime, USVSegment);
                axis(app.WaveformUIAxes, 'tight');
                grid(app.WaveformUIAxes, 'on');
                xlabel(app.WaveformUIAxes, 'Time (s)');
                ylabel(app.WaveformUIAxes, 'Amplitude');
                xlim(app.WaveformUIAxes, [segmentStartSeconds, segmentStartSeconds + segmentLengthSeconds]);
                app.WaveformUIAxes.Title.String = ['USV Waveform - ', name];
                % === Compute and plot spectrogram ===
                windowSize = app.AppSettings.Visualization.SpectrogramWindow;
                overlap = app.AppSettings.Visualization.SpectrogramOverlap;

                [~, f, t, p] = spectrogram(USVSegment, windowSize, overlap, [], fs, 'yaxis');
                f_kHz = f / 1000;
                p_dB = 10 * log10(abs(p) + eps);  % avoid log(0)

                img = imagesc(app.SpectrogramUIAxes, t + segmentStartSeconds, f_kHz, p_dB);
                set(img, ...
                    'HitTest', 'on', ...
                    'PickableParts', 'all', ...
                    'ButtonDownFcn', @(src, event) app.AnySpectrogramUIAxesButtonDown(event));
                axis(app.SpectrogramUIAxes, 'xy');
                xlabel(app.SpectrogramUIAxes, 'Time (s)');
                ylabel(app.SpectrogramUIAxes, 'Frequency (kHz)');
                app.SpectrogramUIAxes.Title.String = ['USV Spectrogram - ', name];


                c = app.AppSettings.Visualization.SpectrogramColormap;
                if c == "invgray"
                    c = flipud(gray);
                end
                colormap(app.SpectrogramUIAxes, c);
                ylim(app.SpectrogramUIAxes, [app.AppSettings.Visualization.SpectrogramMinFrequency, ...
                    app.AppSettings.Visualization.SpectrogramMaxFrequency]);
                xlim(app.SpectrogramUIAxes, [segmentStartSeconds, segmentStartSeconds + segmentLengthSeconds]);

                % === Set identical x-ticks ===
                xtickVals = linspace(segmentStartSeconds, segmentStartSeconds + segmentLengthSeconds, 11);
                set(app.WaveformUIAxes, 'XTick', xtickVals);
                set(app.SpectrogramUIAxes, 'XTick', xtickVals);


                %%%%% LABELS %%%%%
                minFreq = app.AppSettings.Visualization.SpectrogramMinFrequency;
                maxFreq = app.AppSettings.Visualization.SpectrogramMaxFrequency;
                showLabels = app.AppSettings.Visualization.ShowLabels;
                showRefLabels = app.AppSettings.Visualization.ShowReferenceLabels;

                % Clear previous label overlays
                hold(app.SpectrogramUIAxes, 'on');
                delete(findall(app.SpectrogramUIAxes, 'Tag', 'LabelOverlay'));

                % Plot Detected Labels
                labelsColor = app.AppSettings.Visualization.LabelColor;
                referenceLabelsColor = app.AppSettings.Visualization.ReferenceLabelColor;

                if showLabels
                    for i = 1:length(app.CurrentInputFile_AR.Label)
                        L = app.CurrentInputFile_AR.Label(i);
                        if L.StartTime >= segmentStartSeconds && L.EndTime <= segmentEndSeconds
                            x1 = L.StartTime; x2 = L.EndTime;
                            % Vertical lines
                            line(app.SpectrogramUIAxes, [x1 x1], [minFreq maxFreq], ...
                                'Color', labelsColor, 'LineWidth', 1.2, 'LineStyle', '--', 'Tag', 'LabelOverlay');
                            line(app.SpectrogramUIAxes, [x2 x2], [minFreq maxFreq], ...
                                'Color', labelsColor, 'LineWidth', 1.2, 'LineStyle', '-.', 'Tag', 'LabelOverlay');
                            % Text
                            if ~strcmp(L.Label, "None")
                                text(app.SpectrogramUIAxes, (x1 + x2) / 2, maxFreq - 3, L.Label, ...
                                    'Color', labelsColor, 'BackgroundColor', 'black', 'FontSize', 12, ...
                                    'HorizontalAlignment', 'center', 'Tag', 'LabelOverlay');
                            end
                        end
                    end
                end

                % Plot Reference Labels
                if showRefLabels
                    for i = 1:length(app.CurrentInputFile_AR.ReferenceLabel)
                        R = app.CurrentInputFile_AR.ReferenceLabel(i);
                        if R.StartTime >= segmentStartSeconds && R.EndTime <= segmentEndSeconds
                            x1 = R.StartTime; x2 = R.EndTime;
                            % Vertical lines
                            line(app.SpectrogramUIAxes, [x1 x1], [minFreq maxFreq], ...
                                'Color', referenceLabelsColor, 'LineWidth', 1.2, 'LineStyle', '--', 'Tag', 'LabelOverlay');
                            line(app.SpectrogramUIAxes, [x2 x2], [minFreq maxFreq], ...
                                'Color', referenceLabelsColor, 'LineWidth', 1.2, 'LineStyle', '-.', 'Tag', 'LabelOverlay');
                            % Text
                            if ~strcmp(R.Label, "None")
                                text(app.SpectrogramUIAxes, (x1 + x2) / 2, maxFreq - 8, R.Label, ...
                                    'Color', referenceLabelsColor, 'BackgroundColor', 'black', 'FontSize', 12, ...
                                    'HorizontalAlignment', 'center', 'Tag', 'LabelOverlay');
                            end
                        end
                    end
                end

                hold(app.SpectrogramUIAxes, 'off');

            catch ME
                warning(ME.identifier, 'Visualization error: %s', ME.message);
            end


            % --- Close loading dialog
            if exist('d', 'var') && isvalid(d)
                pause(0.2);
                close(d);
            end

        end

        % Value changed function: SegmentStartsEditField
        function SegmentStartsEditFieldValueChanged(app, event)
            value = app.SegmentStartsEditField.Value;
            value = round(value, 4);
            % update in settings
            app.AppSettings.Visualization.SegmentStartSeconds = value;
            app.SegmentStartsEditField.Value = value;
            app.PlotButtonPushed();
        end

        % Value changed function: SegmentLengthsEditField
        function SegmentLengthsEditFieldValueChanged(app, event)
            value = app.SegmentLengthsEditField.Value;
            value = round(value, 4);
            % update in settings
            app.AppSettings.Visualization.SegmentLengthSeconds = value;
            app.SegmentLengthsEditField.Value = value;
            app.PlotButtonPushed();
        end

        % Button pushed function: SegmentMoveBackward
        function SegmentMoveBackwardButtonPushed(app, event)
            app.AppSettings.Visualization.SegmentStartSeconds = app.AppSettings.Visualization.SegmentStartSeconds-app.AppSettings.Visualization.SegmentLengthSeconds;
            if app.AppSettings.Visualization.SegmentStartSeconds < 0
                app.AppSettings.Visualization.SegmentStartSeconds = 0;
            end
            app.SegmentStartsEditField.Value = app.AppSettings.Visualization.SegmentStartSeconds;

            % replot
            app.PlotButtonPushed();
        end

        % Button pushed function: SegmentMoveForward
        function SegmentMoveForwardButtonPushed(app, event)
            app.AppSettings.Visualization.SegmentStartSeconds = app.AppSettings.Visualization.SegmentStartSeconds+app.AppSettings.Visualization.SegmentLengthSeconds;
            USVLengthSeconds = length(app.CurrentInputFile_AR.USV) / app.CurrentInputFile_AR.fs;
            if app.AppSettings.Visualization.SegmentStartSeconds > USVLengthSeconds
                app.AppSettings.Visualization.SegmentStartSeconds = USVLengthSeconds-app.AppSettings.Visualization.SegmentLengthSeconds;
            end
            app.SegmentStartsEditField.Value = app.AppSettings.Visualization.SegmentStartSeconds;

            % replot
            app.PlotButtonPushed();
        end

        % Value changed function: ShowLoadingDialogueCheckBox
        function ShowLoadingDialogueCheckBoxValueChanged(app, event)
            value = app.ShowLoadingDialogueCheckBox.Value;
            app.AppSettings.Visualization.ShowLoadingDialog = value;
            app.PlotButtonPushed();
        end

        % Value changed function: SpectrogramWindowSizeEditField, 
        % ...and 1 other component
        function SpectrogramWindowSizeEditFieldValueChanged(app, event)
            value = app.SpectrogramWindowSizeEditField.Value;
            app.AppSettings.Visualization.SpectrogramWindow = value;

            app.PlotButtonPushed();
            app.LoadLabelEditButtonPushed();
        end

        % Value changed function: SpectrogramWindowOverlapEditField, 
        % ...and 1 other component
        function SpectrogramWindowOverlapEditFieldValueChanged(app, event)
            value = app.SpectrogramWindowOverlapEditField.Value;
            app.AppSettings.Visualization.SpectrogramOverlap = value;
            app.PlotButtonPushed();
            app.LoadLabelEditButtonPushed();
        end

        % Value changed function: SpectrogramMinimumFrequencyEditField, 
        % ...and 1 other component
        function SpectrogramMinimumFrequencyEditFieldValueChanged(app, event)
            value = app.SpectrogramMinimumFrequencyEditField.Value;
            app.AppSettings.Visualization.SpectrogramMinFrequency = value;
            app.PlotButtonPushed();
            app.LoadLabelEditButtonPushed();
        end

        % Value changed function: SpectrogramMaximumFrequencyEditField, 
        % ...and 1 other component
        function SpectrogramMaximumFrequencyEditFieldValueChanged(app, event)
            value = app.SpectrogramMaximumFrequencyEditField.Value;
            app.AppSettings.Visualization.SpectrogramMaxFrequency = value;
            app.PlotButtonPushed();
            app.LoadLabelEditButtonPushed();
        end

        % Value changed function: ShowLabelsCheckBox
        function ShowLabelsCheckBoxValueChanged(app, event)
            value = app.ShowLabelsCheckBox.Value;
            app.AppSettings.Visualization.ShowLabels = value;
            app.PlotButtonPushed();
        end

        % Value changed function: ShowReferenceLabelsCheckBox
        function ShowReferenceLabelsCheckBoxValueChanged(app, event)
            value = app.ShowReferenceLabelsCheckBox.Value;
            app.AppSettings.Visualization.ShowReferenceLabels = value;
            app.PlotButtonPushed();
        end

        % Button pushed function: RunDetectorsButton
        function RunDetectorsButtonPushed(app, event)
            % Show progress dialog
            d = uiprogressdlg(app.SqueakPeekStudioUIFigure, ...
                'Title', 'Running Detectors', ...
                'Message', 'Initializing...', ...
                'Indeterminate', 'off', ...
                'Value', 0);

            drawnow;

            selectedDetectors = app.UsedDetectorsListBox.Value;
            selectedPostprocessing = app.UsedPostProcessingListBox.Value;

            if app.AppSettings.DataInput.BatchMode == false

                fs = app.CurrentInputFile_AR.fs;
                usv = app.CurrentInputFile_AR.USV;
                filename = app.CurrentInputFile_AR.USVPath;

                exportDir = app.AppSettings.Detection.ExportPath;
                [~, baseName, ~] = fileparts(filename);
                total = length(selectedDetectors);

                for k = 1:total
                    det = selectedDetectors{k};
                    labelStruct = [];

                    % Estimate time (based on hardcoded reference)
                    sigLen = length(usv);  % samples
                    switch det
                        case 'PSD'
                            estTime = 45 * (sigLen / 161999424);
                        case 'BSCD'
                            estTime = 200 * (sigLen / 161999424);  % adjust
                        case 'RBD'
                            estTime = 1800 * (sigLen / 161999424);  % adjust
                        otherwise
                            estTime = 0.0;
                    end

                    startProgress = (k - 1) / total;
                    targetProgress = k / total;
                    progressStart = tic;

                    % Start non-blocking timer
                    progressTimer = timer( ...
                        'ExecutionMode', 'fixedRate', ...
                        'Period', 0.1, ...
                        'TimerFcn', @(~,~) updateDialog() ...
                        );
                    start(progressTimer);


                    % Run detector
                    detectorStart = tic;
                    switch det
                        case 'PSD'
                            labelStruct = PSDDetector(usv, fs, ...
                                'fcutMin', app.AppSettings.Detection.PSD.fcutMin, ...
                                'fcutMax', app.AppSettings.Detection.PSD.fcutMax, ...
                                'segmentLength', app.AppSettings.Detection.PSD.segmentLength, ...
                                'overlapFactor', app.AppSettings.Detection.PSD.overlapFactor, ...
                                'maWindow', app.AppSettings.Detection.PSD.maWindow, ...
                                'noiseWindow', app.AppSettings.Detection.PSD.noiseWindow, ...
                                'localWindow', app.AppSettings.Detection.PSD.localWindow, ...
                                'k', app.AppSettings.Detection.PSD.k, ...
                                'w', app.AppSettings.Detection.PSD.w, ...
                                'minEffectivePower', app.AppSettings.Detection.PSD.minEffectivePower ...
                                );
                        case 'BSCD'
                            labelStruct = BSCDDetector(usv, fs, ...
                                'fcutMin', app.AppSettings.Detection.BSCD.fcutMin, ...
                                'fcutMax', app.AppSettings.Detection.BSCD.fcutMax, ...
                                'wlen', app.AppSettings.Detection.BSCD.wlen, ...
                                'maWindow', app.AppSettings.Detection.BSCD.maWindow, ...
                                'noiseWindow', app.AppSettings.Detection.BSCD.noiseWindow, ...
                                'localWindow', app.AppSettings.Detection.BSCD.localWindow, ...
                                'k', app.AppSettings.Detection.BSCD.k, ...
                                'w', app.AppSettings.Detection.BSCD.w ...
                                );
                        case 'RBD'
                            labelStruct = RBDDetector(usv, fs, ...
                                'fcutMin', app.AppSettings.Detection.RBD.fcutMin, ...
                                'fcutMax', app.AppSettings.Detection.RBD.fcutMax, ...
                                'wlen', app.AppSettings.Detection.RBD.wlen, ...
                                'AR_order_left', app.AppSettings.Detection.RBD.AR_order_left, ...
                                'AR_order_right', app.AppSettings.Detection.RBD.AR_order_right, ...
                                'Bayesian_Evidence_order', app.AppSettings.Detection.RBD.Bayesian_Evidence_order, ...
                                'dynamicScaling', app.AppSettings.Detection.RBD.dynamicScaling, ...
                                'smoothingWindowRBD', app.AppSettings.Detection.RBD.smoothingWindowRBD, ...
                                'smoothingWindowThr', app.AppSettings.Detection.RBD.smoothingWindowThr, ...
                                'amplitudeThreshold', app.AppSettings.Detection.RBD.amplitudeThreshold ...
                                );
                        otherwise
                            warning("Unknown detector: %s", det);
                            continue;
                    end

                    % Stop progress timer
                    stop(progressTimer);
                    delete(progressTimer);

                    % Post-processing
                    for j = 1:length(selectedPostprocessing)
                        post = selectedPostprocessing{j};
                        switch post
                            case 'Merge Close Labels'
                                labelStruct = mergeCloseLabels(labelStruct, app.AppSettings.Detection.POST.maxGapToMerge);
                            case 'Remove Short Labels'
                                labelStruct = removeShortLabels(labelStruct, app.AppSettings.Detection.POST.minLabelLength);
                        end
                    end

                    % Export
                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                    exportFile = fullfile(exportDir, sprintf('%s_%s_%s_detected.txt', baseName, det, timestamp));
                    % load into Data Input
                    exportLabelsDetector(labelStruct, exportFile);

                    elapsedTime = toc(detectorStart);
                    d.Message = sprintf('Finished %s in %.1f s', det, elapsedTime);
                    d.Value = targetProgress;
                    drawnow;
                end
            end

            if isvalid(d)
                d.Message = 'All detectors finished.';
                pause(0.5);
                close(d);
            end

            % reload input
            app.SelectLabelFieldSingle.Value = exportFile;
            app.LoadFilesButtonPushed();

            % --- Nested timer update function ---
            function updateDialog()
                elapsed = toc(progressStart);
                d.Title = sprintf('Running %s (%d of %d)', det, k, total);
                d.Message = sprintf('Running %s on "%s"\nEstimated time left: %.1f s', ...
                    det, baseName, max(estTime - elapsed, 0));
                d.Value = min(startProgress + ...
                    (targetProgress - startProgress) * (elapsed / estTime), targetProgress);
                drawnow;
            end

        end

        % Button pushed function: LoadLabelEditButton
        function LoadLabelEditButtonPushed(app, event)
            labels = app.CurrentInputFile_AR.Label;
            refLabels = app.CurrentInputFile_AR.ReferenceLabel;

            % If no labels, reset UI and exit
            if isempty(labels)
                disp("No labels loaded. Skipping label editor load.");
                app.LabelEditAllLabels.Value = 0;
                app.EditLabelClassificationEditField.Value = "";
                app.DetectionStateDropDown.Value = "None";
                app.ClassificationStateDropdown.Value = "None";
                cla(app.LabelEditSpectrogramUIAxes);
                return;
            end

            app.LabelEditAllLabels.Value = length(labels);
            i = app.LabelEditCurrentLabel.Value;

            if i < 1 || i > length(labels)
                warning("Invalid label index selected.");
                return;
            end

            L = labels(i);
            midpoint = (L.StartTime + L.EndTime) / 2;
            segmentStartSeconds = max(midpoint - 0.25, 0);
            fs = app.CurrentInputFile_AR.fs;
            USV = app.CurrentInputFile_AR.USV;
            totalDuration = length(USV) / fs;
            segmentEndSeconds = min(midpoint + 0.25, totalDuration);

            segmentStartIndex = round(segmentStartSeconds * fs) + 1;
            segmentEndIndex = round(segmentEndSeconds * fs);
            USVSegment = USV(segmentStartIndex:segmentEndIndex);

            windowSize = app.AppSettings.Visualization.SpectrogramWindow;
            overlap = app.AppSettings.Visualization.SpectrogramOverlap;
            [~, f, t, p] = spectrogram(USVSegment, windowSize, overlap, [], fs, 'yaxis');
            f_kHz = f / 1000;
            p_dB = 10 * log10(abs(p) + eps);


            [~, name, ~] = fileparts(app.CurrentInputFile_AR.USVPath);

            img = imagesc(app.LabelEditSpectrogramUIAxes, t + segmentStartSeconds, f_kHz, p_dB);
            set(img, ...
                'HitTest', 'on', ...
                'PickableParts', 'all', ...
                'ButtonDownFcn', @(src, event) app.AnySpectrogramUIAxesButtonDown(event));
            axis(app.LabelEditSpectrogramUIAxes, 'xy');
            xlabel(app.LabelEditSpectrogramUIAxes, 'Time (s)');
            ylabel(app.LabelEditSpectrogramUIAxes, 'Frequency (kHz)');
            app.LabelEditSpectrogramUIAxes.Title.String = ['USV Spectrogram - ', name];
            c = app.AppSettings.Visualization.SpectrogramColormap;
            if c == "invgray"
                c = flipud(gray);
            end
            colormap(app.LabelEditSpectrogramUIAxes, c);

            minFreq = app.AppSettings.Visualization.SpectrogramMinFrequency;
            maxFreq = app.AppSettings.Visualization.SpectrogramMaxFrequency;

            ylim(app.LabelEditSpectrogramUIAxes, [minFreq, maxFreq]);
            xlim(app.LabelEditSpectrogramUIAxes, [segmentStartSeconds, segmentEndSeconds]);
            disableDefaultInteractivity(app.LabelEditSpectrogramUIAxes);

            hold(app.LabelEditSpectrogramUIAxes, 'on');

            labelsColor = app.AppSettings.Visualization.LabelColor;
            referenceLabelsColor = app.AppSettings.Visualization.ReferenceLabelColor;

            % === Plot detected labels (excluding current label) ===
            for j = 1:length(labels)
                if j == i, continue; end  % skip current label
                Lj = labels(j);
                if Lj.StartTime >= segmentStartSeconds && Lj.EndTime <= segmentEndSeconds
                    x1 = Lj.StartTime; x2 = Lj.EndTime;
                    line(app.LabelEditSpectrogramUIAxes, [x1 x1], [minFreq maxFreq], ...
                        'Color', labelsColor, 'LineWidth', 1.2, 'LineStyle', '--', 'Tag', 'LabelOverlay');
                    line(app.LabelEditSpectrogramUIAxes, [x2 x2], [minFreq maxFreq], ...
                        'Color', labelsColor, 'LineWidth', 1.2, 'LineStyle', '-.', 'Tag', 'LabelOverlay');
                    if ~strcmp(Lj.Label, "None")
                        text(app.LabelEditSpectrogramUIAxes, (x1 + x2) / 2, maxFreq - 3, Lj.Label, ...
                            'Color', labelsColor, 'BackgroundColor', 'black', 'FontSize', 12, ...
                            'HorizontalAlignment', 'center', 'Tag', 'LabelOverlay');
                    end
                end
            end

            % === Plot reference labels ===
            for j = 1:length(refLabels)
                Rj = refLabels(j);
                if Rj.StartTime >= segmentStartSeconds && Rj.EndTime <= segmentEndSeconds
                    x1 = Rj.StartTime; x2 = Rj.EndTime;
                    line(app.LabelEditSpectrogramUIAxes, [x1 x1], [minFreq maxFreq], ...
                        'Color', referenceLabelsColor, 'LineWidth', 1.2, 'LineStyle', '--', 'Tag', 'LabelOverlay');
                    line(app.LabelEditSpectrogramUIAxes, [x2 x2], [minFreq maxFreq], ...
                        'Color', referenceLabelsColor, 'LineWidth', 1.2, 'LineStyle', '-.', 'Tag', 'LabelOverlay');
                    if ~strcmp(Rj.Label, "None")
                        text(app.LabelEditSpectrogramUIAxes, (x1 + x2) / 2, maxFreq - 8, Rj.Label, ...
                            'Color', referenceLabelsColor, 'BackgroundColor', 'black', 'FontSize', 12, ...
                            'HorizontalAlignment', 'center', 'Tag', 'LabelOverlay');
                    end
                end
            end

            % === Draggable lines for current label ===
            if isfield(app, 'StartLine') && isvalid(app.StartLine)
                delete(app.StartLine);
            end
            if isfield(app, 'EndLine') && isvalid(app.EndLine)
                delete(app.EndLine);
            end

            app.StartLine = drawline(app.LabelEditSpectrogramUIAxes, ...
                'Position', [L.StartTime, minFreq; L.StartTime, maxFreq], ...
                'Color', labelsColor, 'LineWidth', 2.5);
            addlistener(app.StartLine, 'ROIMoved', @(src, evt) labelEditUpdateStartTime(app, src));

            app.EndLine = drawline(app.LabelEditSpectrogramUIAxes, ...
                'Position', [L.EndTime, minFreq; L.EndTime, maxFreq], ...
                'Color', labelsColor, 'LineWidth', 2.5);
            addlistener(app.EndLine, 'ROIMoved', @(src, evt) labelEditUpdateEndTime(app, src));

            % Show current label text as well
            if ~strcmp(L.Label, "None")
                text(app.LabelEditSpectrogramUIAxes, (L.StartTime + L.EndTime) / 2, maxFreq - 3, L.Label, ...
                    'Color', labelsColor, 'BackgroundColor', 'black', 'FontSize', 12, ...
                    'HorizontalAlignment', 'center', 'FontWeight', 'bold', 'Tag', 'LabelOverlay');
            end

            hold(app.LabelEditSpectrogramUIAxes, 'off');

            app.EditLabelClassificationEditField.Value = L.Label;
            if isfield(L, 'DetectionState') && ~isempty(L.DetectionState)
                app.DetectionStateDropDown.Value = L.DetectionState;
            else
                app.DetectionStateDropDown.Value = "None";
            end

            if isfield(L, 'ClassificationState') && ~isempty(L.ClassificationState)
                app.ClassificationStateDropdown.Value = L.ClassificationState;
            else
                app.ClassificationStateDropdown.Value = "None";
            end

            % if classification has been rejected, show editing
            if app.ClassificationStateDropdown.Value == "Rejected"
                app.ShowClassificationEdit();
            else
                app.EditLabelClassificationPanel.Visible = "off";
            end

        end

        % Value changed function: SpectrogramColormapDropDown, 
        % ...and 1 other component
        function SpectrogramColormapDropDownValueChanged(app, event)
            value = app.SpectrogramColormapDropDown.Value;
            app.AppSettings.Visualization.SpectrogramColormap = value;

            app.LoadFilesButtonPushed();
            app.LoadLabelEditButtonPushed();
        end

        % Value changed function: LabelColorDropDown, LabelColorDropDown_2
        function LabelColorDropDownValueChanged(app, event)
            value = app.LabelColorDropDown.Value;
            app.AppSettings.Visualization.LabelColor = value;

            app.LoadFilesButtonPushed();
            app.LoadLabelEditButtonPushed();
        end

        % Value changed function: ReferenceLabelColorDropDown, 
        % ...and 1 other component
        function ReferenceLabelColorDropDownValueChanged(app, event)
            value = app.ReferenceLabelColorDropDown.Value;
            app.AppSettings.Visualization.ReferenceLabelColor = value;

            app.LoadFilesButtonPushed();
            app.LoadLabelEditButtonPushed();
        end

        % Value changed function: LabelEditCurrentLabel
        function LabelEditCurrentLabelValueChanged(app, event)
            value = app.LabelEditCurrentLabel.Value;
            app.LoadLabelEditButtonPushed();
        end

        % Button pushed function: LabelEditMoveBackward
        function LabelEditMoveBackwardButtonPushed(app, event)
            if app.LabelEditCurrentLabel.Value > 1
                app.LabelEditCurrentLabel.Value = app.LabelEditCurrentLabel.Value -1;
            end
            app.LoadLabelEditButtonPushed();
        end

        % Button pushed function: LabelEditMoveForward
        function LabelEditMoveForwardButtonPushed(app, event)

            if app.LabelEditCurrentLabel.Value < length(app.CurrentInputFile_AR.Label)
                app.LabelEditCurrentLabel.Value = app.LabelEditCurrentLabel.Value +1;
            end
            app.LoadLabelEditButtonPushed();
        end

        % Value changed function: EditLabelClassificationEditField
        function EditLabelClassificationEditFieldValueChanged(app, event)
            value = app.EditLabelClassificationEditField.Value;
            i = app.LabelEditCurrentLabel.Value;
            app.CurrentInputFile_AR.Label(i).Label = value;

            app.LoadLabelEditButtonPushed();
        end

        % Button pushed function: AcceptDetectionButton
        function AcceptDetectionButtonPushed(app, event)
            i = app.LabelEditCurrentLabel.Value;
            app.CurrentInputFile_AR.Label(i).DetectionState = "Accepted";
            app.DetectionStateDropDown.Value = "Accepted";

            labels = app.CurrentInputFile_AR.Label;

            % Count accepted labels
            acceptedCount = sum(arrayfun(@(L) isfield(L, 'DetectionState') && strcmp(L.DetectionState, "Accepted"), labels));
            rejectedCount = sum(arrayfun(@(L) isfield(L, 'DetectionState') && strcmp(L.DetectionState, "Rejected"), labels));

            app.AcceptedDetectionsEditField.Value = acceptedCount;
            app.RejectedDetectionsEditField.Value = rejectedCount;

            if app.DetectionStateDropDown.Value == "Accepted" && app.ClassificationStateDropdown.Value == "Accepted"
                app.LabelEditMoveForwardButtonPushed(); % move to next label
                app.LoadLabelEditButtonPushed();
            end
        end

        % Button pushed function: RejectDetectionButton
        function RejectDetectionButtonPushed(app, event)
            i = app.LabelEditCurrentLabel.Value;
            app.CurrentInputFile_AR.Label(i).DetectionState = "Rejected";
            app.DetectionStateDropDown.Value = "Rejected";

            labels = app.CurrentInputFile_AR.Label;

            % Count accepted and rejected labels
            acceptedCount = sum(arrayfun(@(L) isfield(L, 'DetectionState') && strcmp(L.DetectionState, "Accepted"), labels));
            rejectedCount = sum(arrayfun(@(L) isfield(L, 'DetectionState') && strcmp(L.DetectionState, "Rejected"), labels));

            app.AcceptedDetectionsEditField.Value = acceptedCount;
            app.RejectedDetectionsEditField.Value = rejectedCount;

            % Reload and move to next
            app.LoadLabelEditButtonPushed();
            if app.DetectionStateDropDown.Value == "Accepted" && app.ClassificationStateDropdown.Value == "Accepted"
                app.LabelEditMoveForwardButtonPushed(); % move to next label
                app.LoadLabelEditButtonPushed();
            end
        end

        % Button pushed function: ExportLabelsButton
        function ExportLabelsButtonPushed(app, event)
            % Get original file name (without extension)
            [~, baseName, ~] = fileparts(app.CurrentInputFile_AR.LabelPath);

            % Grab all labels
            labels = app.CurrentInputFile_AR.Label;

            % === Rename the rejected classifications by prefixing 'x' ===
            for k = 1:numel(labels)
                if isfield(labels(k), 'ClassificationState') && ...
                        strcmp(labels(k).ClassificationState, "Rejected")
                    labels(k).Label = "x" + labels(k).Label;
                end
            end

            % === Count how many detections were rejected ===
            numDetectionsRejected = sum( arrayfun(@(L) ...
                isfield(L, 'DetectionState') && strcmp(L.DetectionState, "Rejected"), ...
                labels) );

            % === Count how many classifications were rejected ===
            numClassificationsRejected = sum( arrayfun(@(L) ...
                isfield(L, 'ClassificationState') && strcmp(L.ClassificationState, "Rejected"), ...
                labels) );

            % Build new filename
            suggestedName = sprintf('%s_RD%d_RC%d.txt', ...
                baseName, ...
                numDetectionsRejected, ...
                numClassificationsRejected);
            [fileName, folderPath] = uiputfile('*.txt', 'Save Labels As', suggestedName);
            if isequal(fileName, 0) || isequal(folderPath, 0)
                return; % User cancelled
            end
            fullPath = fullfile(folderPath, fileName);

            % === Export everything (with 'x'-prefixed rejected classifications) ===
            exportLabels(labels, fullPath);
        end

        % Button pushed function: SelectDetectorsExportFolder
        function SelectDetectorsExportFolderButtonPushed(app, event)
            folderPath = uigetdir();
            if isequal(folderPath, 0)
                return; % User canceled
            end
            app.SelectDetectorsExportFolderEditField.Value = folderPath;
        end

        % Value changed function: SelectDetectorsExportFolderEditField
        function SelectDetectorsExportFolderEditFieldValueChanged(app, event)
            value = app.SelectDetectorsExportFolderEditField.Value;
            app.AppSettings.Detection.ExportPath = value;
        end

        % Button pushed function: ReloadMetricsButton
        function ReloadMetricsButtonPushed(app, event)
            in = app.CurrentInputFile;

            % Check if both label files exist
            if isfile(in.ReferenceLabelPath) && isfile(in.LabelPath)
                try
                    % Proceed to compare the labels
                    metrics = compareLabels(in.ReferenceLabelPath, in.LabelPath, in.fs);

                    % Update the UI with metrics
                    [~, name, ~] = fileparts(app.CurrentInputFile_AR.USVPath);
                    app.DetectionmetricsLabel.Text = ['Detection metrics for ', name];

                    app.ReferenceLabelsMetricsEditField.Value = metrics.TotalProvidedLabels;
                    app.DetectedLabelsMetricsEditField.Value = metrics.TotalDetectedLabels;
                    app.TruePositivesMetricsEditField.Value = metrics.TruePositives;
                    app.FalseNegativesMetricsEditField.Value = metrics.FalseNegatives;
                    app.PrecisionMetricsEditField.Value = metrics.Precision;
                    app.RecallMetricsEditField.Value = metrics.Recall;
                    app.F1ScoreMetricsEditField.Value = metrics.F1Score;

                catch err
                    % Show a UI alert if an error occurs in compareLabels
                    uialert(app.SqueakPeekStudioUIFigure, ...
                        sprintf("Error comparing labels:\n%s", err.message), ...
                        "Label Comparison Error", "Icon", "warning");
                end
            else
                % If labels are missing, show a message in the UI and skip metrics calculation
                app.DetectionmetricsLabel.Text = 'Metrics not available: Missing label files';

                % Clear metrics fields (optional)
                app.ReferenceLabelsMetricsEditField.Value = 0;
                app.DetectedLabelsMetricsEditField.Value = 0;
                app.TruePositivesMetricsEditField.Value = 0;
                app.FalseNegativesMetricsEditField.Value = 0;
                app.PrecisionMetricsEditField.Value = 0;
                app.RecallMetricsEditField.Value = 0;
                app.F1ScoreMetricsEditField.Value = 0;
            end

        end

        % Image clicked function: Image
        function ImageClicked(app, event)

        end

        % Callback function
        function EditButtonPushed(app, event)
            app.EditLabelClassificationEditField.Editable = "on";
        end

        % Button pushed function: SonifyButton
        function SonifyButtonPushed(app, event)
            %audiodevreset;
            % === Gather parameters ===
            audioFile = app.CurrentInputFile_AR.USV;
            fs        = app.CurrentInputFile_AR.fs;
            startTime = app.SegmentStartsEditField.Value;
            segLen    = app.SegmentLengthsEditField.Value;
            endTime   = startTime + segLen;
            ST = app.SonificationSTEditField.Value;
            slowdown  = app.SonificationSlowdownEditField.Value;
            targetFs  = 44100;
            winLen    = 1024;
            winHop    = winLen/4;

            % === Sonify the segment ===
            signal_out = app.sonifySegment( ...
                audioFile, startTime, endTime, ...
                fs, ST, slowdown, targetFs, ...
                winLen, winHop);

            % === Compute mapping ratio ===
            durOriginal = segLen;                         % (endTime-startTime)
            durOut      = length(signal_out)/targetFs;    % actual playback length
            ratio       = durOriginal/durOut;             % how to map back

            % === Create & start the player ===
            player = audioplayer(signal_out, targetFs);
            play(player);

            % === Draw initial play‐lines ===
            hold(app.WaveformUIAxes,    'on');
            p1 = plot(app.WaveformUIAxes,   [startTime startTime], app.WaveformUIAxes.YLim, 'r','LineWidth',2);
            hold(app.WaveformUIAxes,    'off');
            hold(app.SpectrogramUIAxes, 'on');
            p2 = plot(app.SpectrogramUIAxes,[startTime startTime], app.SpectrogramUIAxes.YLim,'r','LineWidth',2);
            hold(app.SpectrogramUIAxes,'off');

            % === Animate until playback truly stops ===
            while isplaying(player)
                elapsedSamples = player.CurrentSample;
                elapsedTime    = elapsedSamples/targetFs;        % in stretched-time
                currentTime    = startTime + elapsedTime*ratio;  % map to original-time

                if isvalid(p1), p1.XData = [currentTime currentTime]; end
                if isvalid(p2), p2.XData = [currentTime currentTime]; end

                pause(0.01);
            end

            % === Clean up lines ===
            if isvalid(p1), delete(p1); end
            if isvalid(p2), delete(p2); end
        end

        % Drop down opening function: SelectUSVDropDown
        function SelectUSVDropDownOpening(app, event)
            app.checkFileCount("USVs");
        end

        % Drop down opening function: SelectReferenceLabelDropDown
        function SelectReferenceLabelDropDownOpening(app, event)

            app.checkFileCount("ReferenceLabels");

        end

        % Drop down opening function: SelectDetectedLabelDropDown
        function SelectDetectedLabelDropDownOpening(app, event)
            app.checkFileCount("Labels");
        end

        % Value changed function: DetectionStateDropDown
        function DetectionStateDropDownValueChanged(app, event)
            value = app.DetectionStateDropDown.Value;

            switch value
                case "Accepted"
                    AcceptDetectionButtonPushed(app, struct());
                case "Rejected"
                    RejectDetectionButtonPushed(app, struct());
                case "None"
                    i = app.LabelEditCurrentLabel.Value;
                    app.CurrentInputFile_AR.Label(i).DetectionState = "None";
                    app.DetectionStateDropDown.Value = "None";

                    labels = app.CurrentInputFile_AR.Label;

                    acceptedCount = sum(arrayfun(@(L) isfield(L, 'DetectionState') && strcmp(L.DetectionState, "Accepted"), labels));
                    rejectedCount = sum(arrayfun(@(L) isfield(L, 'DetectionState') && strcmp(L.DetectionState, "Rejected"), labels));

                    app.AcceptedDetectionsEditField.Value = acceptedCount;
                    app.RejectedDetectionsEditField.Value = rejectedCount;

                    app.LoadLabelEditButtonPushed();
            end
        end

        % Button down function: LabelEditSpectrogramUIAxes, 
        % ...and 1 other component
        function AnySpectrogramUIAxesButtonDown(app, event)
            % Only respond to right-clicks
            if ~strcmp(app.SqueakPeekStudioUIFigure.SelectionType, 'alt')
                return;
            end

            %disp("Right click detected");

            % === Get clicked time
            clickPoint      = event.IntersectionPoint;  % [X Y Z]
            clickedTime     = clickPoint(1);
            labelDuration   = app.AppSettings.Visualization.ManualLabelLength;
            % === Center the label on the click
            newStart        = clickedTime - labelDuration/2;
            newEnd          = clickedTime + labelDuration/2;

            % === Create label from template (safe dynamic field match)
            if ~isfield(app.CurrentInputFile_AR, 'Label') || isempty(app.CurrentInputFile_AR.Label)
                % Create minimal default label if none exist yet
                newLabel = struct( ...
                    'StartTime', newStart, ...
                    'EndTime',   newEnd,   ...
                    'Label',     "None",   ...
                    'DetectionState',     "Accepted" ...
                    );
                app.CurrentInputFile_AR.Label = newLabel;
            else
                % Copy template and override relevant fields
                template = app.CurrentInputFile_AR.Label(1);
                newLabel = template;

                if isfield(newLabel, 'StartTime'), newLabel.StartTime = newStart; end
                if isfield(newLabel, 'EndTime'),   newLabel.EndTime   = newEnd;   end
                if isfield(newLabel, 'Label'),     newLabel.Label     = "md";     end
                if isfield(newLabel, 'DetectionState'),     newLabel.DetectionState     = "Accepted"; end

                % Safe append
                app.CurrentInputFile_AR.Label(end+1) = newLabel;
            end

            % === Sort labels
            [~, idx] = sort([app.CurrentInputFile_AR.Label.StartTime]);
            app.CurrentInputFile_AR.Label = app.CurrentInputFile_AR.Label(idx);

            app.PlotButtonPushed();
            app.LoadLabelEditButtonPushed();
        end

        % Value changed function: ManualLabelLengthEditField, 
        % ...and 1 other component
        function ManualLabelLengthEditFieldValueChanged(app, event)
            value = app.ManualLabelLengthEditField.Value;
            app.AppSettings.Visualization.ManualLabelLength = value;
        end

        % Key press function: SqueakPeekStudioUIFigure
        function SqueakPeekStudioUIFigureKeyPress(app, event)
            key       = event.Key;
            mods      = event.Modifier;
            tabTitle  = app.TabGroup.SelectedTab.Title;

            switch key
                % ←/→ still always work
                case 'leftarrow'
                    if strcmp(tabTitle, 'Visualization')
                        app.SegmentMoveBackwardButtonPushed();
                    elseif strcmp(tabTitle, 'Label Edit')
                        app.LabelEditMoveBackwardButtonPushed();
                    end

                case 'rightarrow'
                    if strcmp(tabTitle, 'Visualization')
                        app.SegmentMoveForwardButtonPushed();
                    elseif strcmp(tabTitle, 'Label Edit')
                        app.LabelEditMoveForwardButtonPushed();
                    end

                    % only when Label Edit tab is active:
                case 'c'
                    if strcmp(tabTitle, 'Label Edit')
                        if any(strcmp(mods,'shift'))
                            app.RejectClassificationButtonPushed();
                        else
                            app.AcceptClassificationButtonPushed();
                        end
                    end

                case 'd'
                    if strcmp(tabTitle, 'Label Edit')
                        if any(strcmp(mods,'shift'))
                            app.RejectDetectionButtonPushed();
                        else
                            app.AcceptDetectionButtonPushed();
                        end
                    end

                case 'space'
                    if strcmp(tabTitle, 'Label Edit')
                        app.AcceptDetectionButtonPushed();
                        app.AcceptClassificationButtonPushed();
                    end

                otherwise
                    % ignore
            end
        end

        % Button pushed function: AcceptClassificationButton
        function AcceptClassificationButtonPushed(app, event)
            i = app.LabelEditCurrentLabel.Value;
            app.CurrentInputFile_AR.Label(i).ClassificationState = "Accepted";
            app.ClassificationStateDropdown.Value = "Accepted";

            labels = app.CurrentInputFile_AR.Label;

            % Count accepted labels
            acceptedCount = sum(arrayfun(@(L) isfield(L, 'ClassificationState') && strcmp(L.ClassificationState, "Accepted"), labels));
            rejectedCount = sum(arrayfun(@(L) isfield(L, 'ClassificationState') && strcmp(L.ClassificationState, "Rejected"), labels));

            app.AcceptedClassificationsEditField.Value = acceptedCount;
            app.RejectedClassificationsEditField.Value = rejectedCount;

            % Hide Classification Edit Fields
            app.EditLabelClassificationPanel.Visible = "off";

            if app.DetectionStateDropDown.Value == "Accepted" && app.ClassificationStateDropdown.Value == "Accepted"
                app.LabelEditMoveForwardButtonPushed(); % move to next label
                app.LoadLabelEditButtonPushed();
            end
        end

        % Button pushed function: RejectClassificationButton
        function RejectClassificationButtonPushed(app, event)
            i = app.LabelEditCurrentLabel.Value;
            app.CurrentInputFile_AR.Label(i).ClassificationState = "Rejected";
            app.ClassificationStateDropdown.Value = "Rejected";

            labels = app.CurrentInputFile_AR.Label;

            % Count accepted and rejected labels
            acceptedCount = sum(arrayfun(@(L) isfield(L, 'ClassificationState') && strcmp(L.ClassificationState, "Accepted"), labels));
            rejectedCount = sum(arrayfun(@(L) isfield(L, 'ClassificationState') && strcmp(L.ClassificationState, "Rejected"), labels));

            app.AcceptedClassificationsEditField.Value = acceptedCount;
            app.RejectedClassificationsEditField.Value = rejectedCount;


            % Show Classification Edit
            app.ShowClassificationEdit();
            % Reload and move to next
            app.LoadLabelEditButtonPushed();

            if app.DetectionStateDropDown.Value == "Accepted" && app.ClassificationStateDropdown.Value == "Accepted"
                app.LabelEditMoveForwardButtonPushed(); % move to next label
                app.LoadLabelEditButtonPushed();
            end
        end

        % Value changed function: ClassificationsEditField
        function ClassificationsEditFieldValueChanged(app, event)
            % Read raw classification types from the edit field
            rawInput   = app.ClassificationsEditField.Value;    % char or cellstr
            app.AppSettings.LabelEdit.Classifications = rawInput;

            % Normalize to one long string
            if iscell(rawInput)
                rawStr = strjoin(rawInput, newline);
            else
                rawStr = rawInput;
            end

            % Split on newlines, commas or semicolons
            tokens        = regexp(rawStr, '[\r\n,;]+', 'split');
            tokens        = strtrim(tokens);                    % trim whitespace
            tokens        = tokens(~cellfun(@isempty, tokens)); % drop blanks
            uniqueTypes   = unique(tokens, 'stable');           % dedupe, keep order

            % Remember what was selected before
            prevValChar = char(app.ClassificationDropdown.Value);

            % Update dropdown items
            app.ClassificationDropdown.Items = uniqueTypes;

            % Safely restore previous value if it still exists,
            % otherwise pick the first new item (or empty if none)
            if any(strcmp(uniqueTypes, prevValChar))
                app.ClassificationDropdown.Value = prevValChar;
            elseif ~isempty(uniqueTypes)
                app.ClassificationDropdown.Value = uniqueTypes{1};
            else
                app.ClassificationDropdown.Value = "";  % no items → empty value
            end
        end

        % Value changed function: ClassificationDropdown
        function ClassificationDropdownValueChanged(app, event)
            % Grab current dropdown items (could be cellstr or string array)
            value = app.ClassificationDropdown.Value;
            app.EditLabelClassificationEditField.Value = value;

            app.EditLabelClassificationEditFieldValueChanged();
        end

        % Value changed function: ClassificationStateDropdown
        function ClassificationStateDropdownValueChanged(app, event)
            value = app.ClassificationStateDropdown.Value;

            switch value
                case "Accepted"
                    AcceptClassificationButtonPushed(app, struct());
                case "Rejected"
                    RejectClassificationButtonPushed(app, struct());
                case "None"
                    i = app.LabelEditCurrentLabel.Value;
                    app.CurrentInputFile_AR.Label(i).ClassificationState = "None";
                    app.ClassificationStateDropdown.Value = "None";

                    labels = app.CurrentInputFile_AR.Label;

                    acceptedCount = sum(arrayfun(@(L) isfield(L, 'ClassificationState') && strcmp(L.ClassificationState, "Accepted"), labels));
                    rejectedCount = sum(arrayfun(@(L) isfield(L, 'ClassificationStateDropdown') && strcmp(L.ClassificationState, "Rejected"), labels));

                    app.AcceptedClassificationsEditField.Value = acceptedCount;
                    app.RejectedClassificationsEditField.Value = rejectedCount;
            end
            app.LoadLabelEditButtonPushed();
        end

        % Button pushed function: SelectVideoButtonSingle_2
        function SelectVideoButtonSingle_2Pushed(app, event)
            [tempFile, tempPath] = uigetfile({'*.mp4', 'Video'}, 'Select Video');
            if ~isequal(tempFile, 0)
                app.SelectVideoFieldSingle_2.Value = fullfile(tempPath, tempFile);
            end

        end

        % Callback function
        function SelectVideoSyncExportFolderButtonPushed(app, event)
            tempPath = uigetdir('', 'Select Videos Folder');
            if ~isequal(tempPath, 0)
                app.SelectVideoSyncExportFolderEditField.Value = tempPath;
            end
        end

        % Button pushed function: SynchronizeVideoButton
        function SynchronizeVideoButtonPushed(app, event)
            %=== Require Computer Vision Toolbox for AVI muxing ===
            if ~exist('vision.VideoFileWriter','class')
                uialert(app.SqueakPeekStudioUIFigure, ...
                    'Computer Vision Toolbox is required to export video+audio.', ...
                    'Missing Toolbox');
                return;
            end

            %=== Gather & validate ===
            videoPath       = app.SelectVideoFieldSingle_2.Value;
            syncVideoStart  = app.SynchronizationTimeinVideoEditField.Value;  % sec into video
            audioChoice     = app.AudioOutputDropDown.Value;                  % 'Original From Video','USV','Sonified'
            refs            = app.CurrentInputFile.ReferenceLabel;            % table or struct array
            usvPath         = app.CurrentInputFile_AR.USVPath;                % your USV file

            if isempty(videoPath) || isempty(refs) || isempty(usvPath)
                uialert(app.SqueakPeekStudioUIFigure, ...
                    'Please select video, USV file, and load reference labels.', ...
                    'Missing Inputs');
                return;
            end

            %=== Progress UI & disable button ===
            dlg = uiprogressdlg(app.SqueakPeekStudioUIFigure, ...
                'Title','Please Wait', ...
                'Message','Exporting video…', ...
                'Indeterminate','on');
            app.SynchronizeVideoButton.Enable = 'off';

            try
                %--- Find “sk” midpoint (for USV/Sonified) ---
                if istable(refs)
                    mask      = strcmp(refs.Label,'sk');
                    if ~any(mask), error('NoLabel','No label "sk" found.'); end
                    row       = refs(mask,:);
                    midSkTime = (row.StartTime + row.EndTime)/2;
                elseif isstruct(refs)
                    iSK       = find(strcmp({refs.Label},'sk'),1);
                    if isempty(iSK), error('NoLabel','No label "sk" found.'); end
                    midSkTime = (refs(iSK).StartTime + refs(iSK).EndTime)/2;
                else
                    error('NoLabel','ReferenceLabel must be table or struct.');
                end

                %--- Ask user where to save (AVI container) ---
                [~, usvBase, ~] = fileparts(usvPath);
                safeType        = strrep(audioChoice,' ','');
                aviName         = sprintf('%s_%s.avi', usvBase, safeType);
                [f,p] = uiputfile('*.avi','Save synchronized video as',aviName);
                if isequal(f,0), return; end
                aviFile = fullfile(p,f);

                %--- Open video & clamp start time ---
                vr               = VideoReader(videoPath);
                syncVideoStart   = max(0, min(syncVideoStart, vr.Duration));
                frameRate        = vr.FrameRate;
                vr.CurrentTime   = syncVideoStart;

                %--- Sonification parameters ---
                ST       = app.SonificationSTEditField.Value;        % semitones
                slowdown = 1;          % time-stretch factor
                winLen   = 1024;       % STFT window length
                winHop   = winLen/4;   % STFT hop size

                %--- Load or generate audio & choose alignment ---
                switch audioChoice
                    case 'Original From Video'
                        [audioIn,fs]    = audioread(videoPath);   % extract embedded track
                        audioAlignTime  = syncVideoStart;

                    case 'USV'
                        [audioIn,fs]    = audioread(usvPath);
                        audioAlignTime  = midSkTime;

                    case 'Sonified'
                        % --- use your sonifySegment helper on the full USV from midSkTime to end ---
                        [raw,fs]        = audioread(usvPath);
                        % Assuming raw is the audio and fs is the sampling rate
                        bpFilt = designfilt('bandpassiir', ...
                            'FilterOrder', 12, ...
                            'HalfPowerFrequency1', 40000, ...
                            'HalfPowerFrequency2', 120000, ...
                            'SampleRate', fs);

                        raw = filtfilt(bpFilt, raw);  % Apply zero-phase bandpass filtering
                        signal_out      = app.sonifySegment( ...
                            raw, ...
                            midSkTime, ...
                            length(raw)/fs, ...
                            fs, ST, slowdown, fs, winLen, winHop);
                        audioIn         = signal_out;     % now contains the processed audio
                        audioAlignTime  = 0;              % we start at the beginning of signal_out

                    otherwise
                        [audioIn,fs]    = audioread(usvPath);
                        audioAlignTime  = midSkTime;
                end

                %--- Trim audio & compute per-frame sample count ---
                s0              = max(1, floor(audioAlignTime*fs) + 1);
                audioTrim       = audioIn(s0:end, :);
                totalFrames     = floor((vr.Duration - syncVideoStart)*frameRate);
                samplesPerFrame = floor(size(audioTrim,1)/totalFrames);


                if size(audioIn,2) > 1
                    audioIn = mean(audioIn, 2);  % Force mono
                end
                if samplesPerFrame < 1
                    error('Audio','Not enough audio samples for video duration.');
                end
                neededSamples   = totalFrames * samplesPerFrame;
                curSamples      = size(audioTrim,1);
                if curSamples < neededSamples
                    audioTrim(end+1:neededSamples,1) = 0;
                else
                    audioTrim = audioTrim(1:neededSamples,:);
                end

                %--- Mux AVI + audio ---
                writer = vision.VideoFileWriter( ...
                    aviFile, ...
                    'FileFormat',     'AVI', ...
                    'FrameRate',      frameRate, ...
                    'AudioInputPort', true);
                for k = 1:totalFrames
                    if ~hasFrame(vr), break; end
                    frame = readFrame(vr);
                    i0    = (k-1)*samplesPerFrame + 1;
                    i1    = k*samplesPerFrame;
                    seg   = audioTrim(i0:i1,:);
                    step(writer, frame, seg);
                end
                release(writer);

                %--- Optional: recompress AVI → MP4 via ffmpeg if available ---
                [ok,~] = system('ffmpeg -version >NUL 2>&1');
                if ok==0
                    mp4File = fullfile(p, [usvBase '_' safeType '.mp4']);
                    cmd     = sprintf(['ffmpeg -y -i "%s" -c:v libx264 ' ...
                        '-preset medium -crf 23 -c:a aac "%s"'], ...
                        aviFile, mp4File);
                    if system(cmd)==0
                        delete(aviFile);
                        outFile = mp4File;
                    else
                        outFile = aviFile;
                        uialert(app.SqueakPeekStudioUIFigure, ...
                            'ffmpeg compression failed; AVI kept.', ...
                            'Mux Warning');
                    end
                else
                    outFile = aviFile;
                end

                uialert(app.SqueakPeekStudioUIFigure, ...
                    sprintf('Saved synchronized video:\n%s', outFile), ...
                    'Done');

            catch ME
                delete(dlg);
                app.SynchronizeVideoButton.Enable = 'on';
                if strcmp(ME.identifier,'NoLabel')
                    uialert(app.SqueakPeekStudioUIFigure, ME.message, 'Sync Error');
                else
                    uialert(app.SqueakPeekStudioUIFigure, ...
                        'Unexpected error during export.', 'Error');
                    rethrow(ME);
                end
                return;
            end

            %=== Restore UI ===
            delete(dlg);
            app.SynchronizeVideoButton.Enable = 'on';

        end

        % Value changed function: SonificationSTEditField
        function SonificationSTEditFieldValueChanged(app, event)
            value = app.SonificationSTEditField.Value;
            app.AppSettings.Visualization.SonificationST = value;
        end

        % Value changed function: SonificationSlowdownEditField
        function SonificationSlowdownEditFieldValueChanged(app, event)
            value = app.SonificationSlowdownEditField.Value;
            app.AppSettings.Visualization.SonificationSlowdown = value;
        end

        % Value changed function: SelectUSVFieldBatch
        function SelectUSVFieldBatchValueChanged(app, event)
            app.checkFileCount("USVs");
        end

        % Value changed function: SelectReferenceLabelFieldBatch
        function SelectReferenceLabelFieldBatchValueChanged(app, event)
            app.checkFileCount("ReferenceLabels");
        end

        % Value changed function: SelectLabelFieldBatch
        function SelectLabelFieldBatchValueChanged(app, event)
            app.checkFileCount("Labels");
        end

        % Value changed function: PSD_fCutMinEditField
        function PSD_fCutMinEditFieldValueChanged(app, event)
            value = app.PSD_fCutMinEditField.Value;
            app.AppSettings.Detection.PSD.fcutMin = value;
        end

        % Value changed function: PSD_fCutMaxEditField
        function PSD_fCutMaxEditFieldValueChanged(app, event)
            value = app.PSD_fCutMaxEditField.Value;
            app.AppSettings.Detection.PSD.fcutMax = value;
        end

        % Value changed function: PSD_SegmentWindowEditField
        function PSD_SegmentWindowEditFieldValueChanged(app, event)
            value = app.PSD_SegmentWindowEditField.Value;
            app.AppSettings.Detection.PSD.segmentLength = value;
        end

        % Value changed function: PSD_WindowOverlapEditField
        function PSD_WindowOverlapEditFieldValueChanged(app, event)
            value = app.PSD_WindowOverlapEditField.Value;
            app.AppSettings.Detection.PSD.overlapFactor = value;
        end

        % Value changed function: PSD_SmoothingWindowEditField
        function PSD_SmoothingWindowEditFieldValueChanged(app, event)
            value = app.PSD_SmoothingWindowEditField.Value;
            app.AppSettings.Detection.PSD.maWindow = value;
        end

        % Value changed function: PSD_NoiseTrackingWindowEditField
        function PSD_NoiseTrackingWindowEditFieldValueChanged(app, event)
            value = app.PSD_NoiseTrackingWindowEditField.Value;
            app.AppSettings.Detection.PSD.noiseWindow = value;
        end

        % Value changed function: PSD_LocalTrackingWindowEditField
        function PSD_LocalTrackingWindowEditFieldValueChanged(app, event)
            value = app.PSD_LocalTrackingWindowEditField.Value;
            app.AppSettings.Detection.PSD.localWindow = value;
        end

        % Value changed function: PSD_kEditField
        function PSD_kEditFieldValueChanged(app, event)
            value = app.PSD_kEditField.Value;
            app.AppSettings.Detection.PSD.k = value;
        end

        % Value changed function: PSD_wEditField
        function PSD_wEditFieldValueChanged(app, event)
            value = app.PSD_wEditField.Value;
            app.AppSettings.Detection.PSD.w = value;
        end

        % Value changed function: PSD_MinPowerEditField
        function PSD_MinPowerEditFieldValueChanged(app, event)
            value = app.PSD_MinPowerEditField.Value;
            app.AppSettings.Detection.PSD.minEffectivePower = value;
        end

        % Value changed function: BSCD_fCutMinEditField
        function BSCD_fCutMinEditFieldValueChanged(app, event)
            value = app.BSCD_fCutMinEditField.Value;
            app.AppSettings.Detection.BSCD.fcutMin = value;
        end

        % Value changed function: BSCD_fCutMaxEditField
        function BSCD_fCutMaxEditFieldValueChanged(app, event)
            value = app.BSCD_fCutMaxEditField.Value;
            app.AppSettings.Detection.BSCD.fcutMax = value;
        end

        % Value changed function: BSCD_WindowLengthEditField
        function BSCD_WindowLengthEditFieldValueChanged(app, event)
            value = app.BSCD_WindowLengthEditField.Value;
            app.AppSettings.Detection.BSCD.wlen = value;
        end

        % Value changed function: BSCD_SmoothingWindowEditField
        function BSCD_SmoothingWindowEditFieldValueChanged(app, event)
            value = app.BSCD_SmoothingWindowEditField.Value;
            app.AppSettings.Detection.BSCD.maWindow = value;
        end

        % Value changed function: BSCD_NoiseTrackingWindowEditField
        function BSCD_NoiseTrackingWindowEditFieldValueChanged(app, event)
            value = app.BSCD_NoiseTrackingWindowEditField.Value;
            app.AppSettings.Detection.BSCD.noiseWindow = value;
        end

        % Value changed function: BSCD_LocalTrackingWindowEditField
        function BSCD_LocalTrackingWindowEditFieldValueChanged(app, event)
            value = app.BSCD_LocalTrackingWindowEditField.Value;
            app.AppSettings.Detection.BSCD.localWindow = value;
        end

        % Value changed function: BSCD_kEditField
        function BSCD_kEditFieldValueChanged(app, event)
            value = app.BSCD_kEditField.Value;
            app.AppSettings.Detection.BSCD.k = value;
        end

        % Value changed function: BSCD_wEditField
        function BSCD_wEditFieldValueChanged(app, event)
            value = app.BSCD_wEditField.Value;
            app.AppSettings.Detection.BSCD.w = value;
        end

        % Value changed function: RBD_fCutMinEditField
        function RBD_fCutMinEditFieldValueChanged(app, event)
            value = app.RBD_fCutMinEditField.Value;
            app.AppSettings.Detection.RBD.fcutMin = value;
        end

        % Value changed function: RBD_fCutMaxEditField
        function RBD_fCutMaxEditFieldValueChanged(app, event)
            value = app.RBD_fCutMaxEditField.Value;
            app.AppSettings.Detection.RBD.fcutMax = value;
        end

        % Value changed function: RBD_wlenEditField
        function RBD_wlenEditFieldValueChanged(app, event)
            value = app.RBD_wlenEditField.Value;
            app.AppSettings.Detection.RBD.wlen = value;
        end

        % Value changed function: RBD_AR_leftEditField
        function RBD_AR_leftEditFieldValueChanged(app, event)
            value = app.RBD_AR_leftEditField.Value;
            app.AppSettings.Detection.RBD.AR_order_left = value;
        end

        % Value changed function: RBD_AR_rightEditField
        function RBD_AR_rightEditFieldValueChanged(app, event)
            value = app.RBD_AR_rightEditField.Value;
            app.AppSettings.Detection.RBD.AR_order_right = value;
        end

        % Value changed function: RBD_BayesianEvidenceEditField
        function RBD_BayesianEvidenceEditFieldValueChanged(app, event)
            value = app.RBD_BayesianEvidenceEditField.Value;
            app.AppSettings.Detection.RBD.Bayesian_Evidence_order = value;
        end

        % Value changed function: RBD_DynamicScalingEditField
        function RBD_DynamicScalingEditFieldValueChanged(app, event)
            value = app.RBD_DynamicScalingEditField.Value;
            app.AppSettings.Detection.RBD.dynamicScaling = value;
        end

        % Value changed function: RBD_SmoothingWindowEditField
        function RBD_SmoothingWindowEditFieldValueChanged(app, event)
            value = app.RBD_SmoothingWindowEditField.Value;
            app.AppSettings.Detection.RBD.smoothingWindowRBD = value;
        end

        % Value changed function: RBD_SmoothingThresholdEditField
        function RBD_SmoothingThresholdEditFieldValueChanged(app, event)
            value = app.RBD_SmoothingThresholdEditField.Value;
            app.AppSettings.Detection.RBD.smoothingWindowThr = value;
        end

        % Value changed function: RBD_AmplitudeThresholdEditField
        function RBD_AmplitudeThresholdEditFieldValueChanged(app, event)
            value = app.RBD_AmplitudeThresholdEditField.Value;
            app.AppSettings.Detection.RBD.amplitudeThreshold = value;
        end

        % Value changed function: POST_MaxGapToMergeEditField
        function POST_MaxGapToMergeEditFieldValueChanged(app, event)
            value = app.POST_MaxGapToMergeEditField.Value;
            app.AppSettings.Detection.POST.maxGapToMerge = value;
        end

        % Value changed function: POST_MinLabelLengthEditField
        function POST_MinLabelLengthEditFieldValueChanged(app, event)
            value = app.POST_MinLabelLengthEditField.Value;
            app.AppSettings.Detection.POST.minLabelLength = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SqueakPeekStudioUIFigure = uifigure('Visible', 'off');
            app.SqueakPeekStudioUIFigure.Name = 'Squeak Peek Studio';
            app.SqueakPeekStudioUIFigure.Icon = fullfile(pathToMLAPP, 'assets', 'logo', 'squeakpeak_logo.png');
            app.SqueakPeekStudioUIFigure.KeyPressFcn = createCallbackFcn(app, @SqueakPeekStudioUIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.SqueakPeekStudioUIFigure);
            app.DataInputTab = uitab(app.TabGroup);
            app.DataInputTab.Title = 'Data Input';
            app.DataInputPanel = uipanel(app.DataInputTab);
            app.DataInputGrid = uigridlayout(app.DataInputPanel);
            app.LoadFilesButton = uibutton(app.DataInputGrid, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.SingleBatchModeButton = uibutton(app.DataInputGrid, 'push');
            app.SingleBatchModeButton.ButtonPushedFcn = createCallbackFcn(app, @SingleBatchModeButtonPushed, true);
            app.SingleBatchModeButton.Text = 'Single Mode';
            app.SingleModePanel = uipanel(app.DataInputGrid);
            app.SingleModeGridGrid = uigridlayout(app.SingleModePanel);
            app.SelectUSVButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectUSVButtonSingle.ButtonPushedFcn = createCallbackFcn(app, @SelectUSVButtonSinglePushed, true);
            app.SelectUSVButtonSingle.Text = 'Select USV (.wav)';
            app.SelectUSVFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectLabelButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectLabelButtonSingle.ButtonPushedFcn = createCallbackFcn(app, @SelectLabelButtonSinglePushed, true);
            app.SelectLabelButtonSingle.Text = 'Select Detected Labels (.txt)';
            app.SelectLabelFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectReferenceLabelFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectReferenceLabelButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectReferenceLabelButtonSingle.ButtonPushedFcn = createCallbackFcn(app, @SelectReferenceLabelButtonSinglePushed, true);
            app.SelectReferenceLabelButtonSingle.Text = 'Select Reference Labels (.txt)';
            app.SelectVideoButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectVideoButtonSingle.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonSinglePushed, true);
            app.SelectVideoButtonSingle.Visible = 'off';
            app.SelectVideoButtonSingle.Text = 'Select Video (.mov)';
            app.SelectVideoFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectVideoFieldSingle.Visible = 'off';
            app.BatchModePanel = uipanel(app.DataInputGrid);
            app.BatchModeGridGrid = uigridlayout(app.BatchModePanel);
            app.SelectUSVButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectUSVButtonBatch.ButtonPushedFcn = createCallbackFcn(app, @SelectUSVButtonBatchPushed, true);
            app.SelectUSVButtonBatch.Text = 'Select USVs (.wav)';
            app.SelectLabeltxtButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectLabeltxtButtonBatch.ButtonPushedFcn = createCallbackFcn(app, @SelectLabeltxtButtonBatchPushed, true);
            app.SelectLabeltxtButtonBatch.Text = 'Select Detected Labels (.txt)';
            app.SelectReferenceLabeltxtButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectReferenceLabeltxtButtonBatch.ButtonPushedFcn = createCallbackFcn(app, @SelectReferenceLabeltxtButtonBatchPushed, true);
            app.SelectReferenceLabeltxtButtonBatch.Text = 'Select Reference Labels (.txt)';
            app.SelectVideomp4ButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectVideomp4ButtonBatch.ButtonPushedFcn = createCallbackFcn(app, @SelectVideomp4ButtonBatchPushed, true);
            app.SelectVideomp4ButtonBatch.Visible = 'off';
            app.SelectVideomp4ButtonBatch.Text = 'Select Videos (.mov)';
            app.SelectLabelFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectLabelFieldBatch.ValueChangedFcn = createCallbackFcn(app, @SelectLabelFieldBatchValueChanged, true);
            app.SelectReferenceLabelFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectReferenceLabelFieldBatch.ValueChangedFcn = createCallbackFcn(app, @SelectReferenceLabelFieldBatchValueChanged, true);
            app.SelectVideoFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectVideoFieldBatch.Visible = 'off';
            app.SelectUSVFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectUSVFieldBatch.ValueChangedFcn = createCallbackFcn(app, @SelectUSVFieldBatchValueChanged, true);
            app.USVBatchFound = uilabel(app.BatchModeGridGrid);
            app.USVBatchFound.Text = '(x)';
            app.LabelBatchFound = uilabel(app.BatchModeGridGrid);
            app.LabelBatchFound.Text = '(x)';
            app.ReferenceLabelBatchFound = uilabel(app.BatchModeGridGrid);
            app.ReferenceLabelBatchFound.Text = '(x)';
            app.VideoBatchFound = uilabel(app.BatchModeGridGrid);
            app.VideoBatchFound.Visible = 'off';
            app.VideoBatchFound.Text = '(x)';
            app.BatchModeLabelText = uilabel(app.BatchModeGridGrid);
            app.BatchModeLabelText.Text = 'You are in batch mode. To use single-file operations such as Visualization, please select the file to work with.';
            app.SelectUSVDropDownLabel = uilabel(app.BatchModeGridGrid);
            app.SelectUSVDropDownLabel.Text = 'Select USV';
            app.SelectUSVDropDown = uidropdown(app.BatchModeGridGrid);
            app.SelectUSVDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectUSVDropDownOpening, true);
            app.SelectReferenceLabelDropDownLabel = uilabel(app.BatchModeGridGrid);
            app.SelectReferenceLabelDropDownLabel.Text = 'Select Reference Label';
            app.SelectReferenceLabelDropDown = uidropdown(app.BatchModeGridGrid);
            app.SelectReferenceLabelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectReferenceLabelDropDownOpening, true);
            app.SelectDetectedLabelDropDownLabel = uilabel(app.BatchModeGridGrid);
            app.SelectDetectedLabelDropDownLabel.Text = 'Select Detected Label';
            app.SelectDetectedLabelDropDown = uidropdown(app.BatchModeGridGrid);
            app.SelectDetectedLabelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectDetectedLabelDropDownOpening, true);
            app.ExplainerDataInput = uilabel(app.DataInputGrid);
            app.ExplainerDataInput.Text = 'Select and load USV audio, reference labels and detection labels for further analysis in single or batch mode.';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.VisualizationPanel = uipanel(app.VisualizationTab);
            app.VisualizationGrid = uigridlayout(app.VisualizationPanel);
            app.WaveformPlot = uipanel(app.VisualizationGrid);
            app.WaveformGrid = uigridlayout(app.WaveformPlot);
            app.WaveformUIAxes = uiaxes(app.WaveformGrid);
            title(app.WaveformUIAxes, 'USV Waveform')
            xlabel(app.WaveformUIAxes, 'X')
            ylabel(app.WaveformUIAxes, 'Y')
            zlabel(app.WaveformUIAxes, 'Z')
            app.SpectrogramPlot = uipanel(app.VisualizationGrid);
            app.SpectrogramGrid = uigridlayout(app.SpectrogramPlot);
            app.SpectrogramUIAxes = uiaxes(app.SpectrogramGrid);
            title(app.SpectrogramUIAxes, 'USV Spectrogram')
            xlabel(app.SpectrogramUIAxes, 'X')
            ylabel(app.SpectrogramUIAxes, 'Y')
            zlabel(app.SpectrogramUIAxes, 'Z')
            app.SpectrogramUIAxes.ButtonDownFcn = createCallbackFcn(app, @AnySpectrogramUIAxesButtonDown, true);
            app.PlotButton = uibutton(app.SpectrogramGrid, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Visible = 'off';
            app.PlotButton.Text = 'Plot';
            app.SegmentMoveBackward = uibutton(app.VisualizationGrid, 'push');
            app.SegmentMoveBackward.ButtonPushedFcn = createCallbackFcn(app, @SegmentMoveBackwardButtonPushed, true);
            app.SegmentMoveBackward.Text = '<';
            app.SegmentMoveForward = uibutton(app.VisualizationGrid, 'push');
            app.SegmentMoveForward.ButtonPushedFcn = createCallbackFcn(app, @SegmentMoveForwardButtonPushed, true);
            app.SegmentMoveForward.Text = '>';
            app.SonifyButton = uibutton(app.VisualizationGrid, 'push');
            app.SonifyButton.ButtonPushedFcn = createCallbackFcn(app, @SonifyButtonPushed, true);
            app.SonifyButton.Text = 'Sonify';
            app.SegmentStartsEditFieldLabel = uilabel(app.VisualizationGrid);
            app.SegmentStartsEditFieldLabel.Text = 'Segment Start (s)';
            app.SegmentStartsEditField = uieditfield(app.VisualizationGrid, 'numeric');
            app.SegmentStartsEditField.ValueDisplayFormat = '%.4f';
            app.SegmentStartsEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentStartsEditFieldValueChanged, true);
            app.SegmentLengthsEditFieldLabel = uilabel(app.VisualizationGrid);
            app.SegmentLengthsEditFieldLabel.Text = 'Segment Length (s)';
            app.SegmentLengthsEditField = uieditfield(app.VisualizationGrid, 'numeric');
            app.SegmentLengthsEditField.ValueDisplayFormat = '%.4f';
            app.SegmentLengthsEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentLengthsEditFieldValueChanged, true);
            app.Label_5 = uilabel(app.VisualizationGrid);
            app.Label_5.Text = {'View waveform and spectrogram segments of the selected USV recording, display reference and detected labels, and play sonification of the selected region.'; ''};
            app.MetricsTab = uitab(app.TabGroup);
            app.MetricsTab.Title = 'Metrics';
            app.ExportPanel = uipanel(app.MetricsTab);
            app.ExportGrid = uigridlayout(app.ExportPanel);
            app.ReloadMetricsButton = uibutton(app.ExportGrid, 'push');
            app.ReloadMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadMetricsButtonPushed, true);
            app.ReloadMetricsButton.Visible = 'off';
            app.ReloadMetricsButton.Text = 'Reload Metrics';
            app.DetectedLabelsEditField_2Label_3 = uilabel(app.ExportGrid);
            app.DetectedLabelsEditField_2Label_3.Text = 'Reference Labels (-)';
            app.ReferenceLabelsMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.ReferenceLabelsMetricsEditField.Editable = 'off';
            app.DetectedLabelsEditField_2Label_4 = uilabel(app.ExportGrid);
            app.DetectedLabelsEditField_2Label_4.Text = 'True Positives (-)';
            app.TruePositivesMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.TruePositivesMetricsEditField.Editable = 'off';
            app.FalseNegativesEditFieldLabel_2 = uilabel(app.ExportGrid);
            app.FalseNegativesEditFieldLabel_2.Text = 'False Negatives (-)';
            app.FalseNegativesMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.FalseNegativesMetricsEditField.Editable = 'off';
            app.PrecisionEditFieldLabel_2 = uilabel(app.ExportGrid);
            app.PrecisionEditFieldLabel_2.Text = 'Precision (%)';
            app.PrecisionMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.PrecisionMetricsEditField.Editable = 'off';
            app.RecallEditFieldLabel_2 = uilabel(app.ExportGrid);
            app.RecallEditFieldLabel_2.Text = 'Recall (%)';
            app.RecallMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.RecallMetricsEditField.Editable = 'off';
            app.F1ScoreEditFieldLabel_2 = uilabel(app.ExportGrid);
            app.F1ScoreEditFieldLabel_2.Text = 'F1 Score (-)';
            app.F1ScoreMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.F1ScoreMetricsEditField.Editable = 'off';
            app.DetectedLabelsEditField_5Label = uilabel(app.ExportGrid);
            app.DetectedLabelsEditField_5Label.Text = 'Detected Labels (-)';
            app.DetectedLabelsMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.DetectedLabelsMetricsEditField.Editable = 'off';
            app.DetectionmetricsLabel = uilabel(app.ExportGrid);
            app.DetectionmetricsLabel.Text = 'Detection metrics';
            app.Label_4 = uilabel(app.ExportGrid);
            app.Label_4.Text = 'Evaluate detection performance by comparing reference and detected labels using standard metrics.';
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.DetectionPanel = uipanel(app.DetectionTab);
            app.DetectionGrid = uigridlayout(app.DetectionPanel);
            app.RunDetectorsButton = uibutton(app.DetectionGrid, 'push');
            app.RunDetectorsButton.ButtonPushedFcn = createCallbackFcn(app, @RunDetectorsButtonPushed, true);
            app.RunDetectorsButton.Text = 'Run Detectors';
            app.DetectorsListBoxLabel = uilabel(app.DetectionGrid);
            app.DetectorsListBoxLabel.Text = 'Detectors';
            app.UsedDetectorsListBox = uilistbox(app.DetectionGrid);
            app.UsedDetectorsListBox.Items = {'PSD', 'BSCD', 'RBD'};
            app.UsedDetectorsListBox.Multiselect = 'on';
            app.UsedDetectorsListBox.Value = {'PSD'};
            app.SelectDetectorsExportFolder = uibutton(app.DetectionGrid, 'push');
            app.SelectDetectorsExportFolder.ButtonPushedFcn = createCallbackFcn(app, @SelectDetectorsExportFolderButtonPushed, true);
            app.SelectDetectorsExportFolder.Text = 'Select Export Folder';
            app.SelectDetectorsExportFolderEditField = uieditfield(app.DetectionGrid, 'text');
            app.SelectDetectorsExportFolderEditField.ValueChangedFcn = createCallbackFcn(app, @SelectDetectorsExportFolderEditFieldValueChanged, true);
            app.PostProcessingLabel = uilabel(app.DetectionGrid);
            app.PostProcessingLabel.Text = 'Post–processing';
            app.UsedPostProcessingListBox = uilistbox(app.DetectionGrid);
            app.UsedPostProcessingListBox.Items = {'None', 'Merge Close Labels', 'Remove Short Labels'};
            app.UsedPostProcessingListBox.Multiselect = 'on';
            app.UsedPostProcessingListBox.Value = {'Merge Close Labels'};
            app.ExplainerDetection = uilabel(app.DetectionGrid);
            app.ExplainerDetection.Text = 'Select one or more detectors and post-processing options, then run detection to export results to a chosen folder. ';
            app.LabelEditTab = uitab(app.TabGroup);
            app.LabelEditTab.Title = 'Label Edit';
            app.LabelEditPanel = uipanel(app.LabelEditTab);
            app.LabelEditGrid = uigridlayout(app.LabelEditPanel);
            app.LabelEditSpectrogramUIAxes = uiaxes(app.LabelEditGrid);
            title(app.LabelEditSpectrogramUIAxes, 'USV Spectrogram')
            xlabel(app.LabelEditSpectrogramUIAxes, 'X')
            ylabel(app.LabelEditSpectrogramUIAxes, 'Y')
            zlabel(app.LabelEditSpectrogramUIAxes, 'Z')
            app.LabelEditSpectrogramUIAxes.ButtonDownFcn = createCallbackFcn(app, @AnySpectrogramUIAxesButtonDown, true);
            app.AcceptDetectionButton = uibutton(app.LabelEditGrid, 'push');
            app.AcceptDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptDetectionButtonPushed, true);
            app.AcceptDetectionButton.Text = 'Accept Detection';
            app.RejectDetectionButton = uibutton(app.LabelEditGrid, 'push');
            app.RejectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @RejectDetectionButtonPushed, true);
            app.RejectDetectionButton.Text = 'Reject Detection';
            app.LabelEditMoveBackward = uibutton(app.LabelEditGrid, 'push');
            app.LabelEditMoveBackward.ButtonPushedFcn = createCallbackFcn(app, @LabelEditMoveBackwardButtonPushed, true);
            app.LabelEditMoveBackward.Text = '<';
            app.LabelEditMoveForward = uibutton(app.LabelEditGrid, 'push');
            app.LabelEditMoveForward.ButtonPushedFcn = createCallbackFcn(app, @LabelEditMoveForwardButtonPushed, true);
            app.LabelEditMoveForward.Text = '>';
            app.EditFieldLabel = uilabel(app.LabelEditGrid);
            app.EditFieldLabel.Text = '#';
            app.LabelEditCurrentLabel = uieditfield(app.LabelEditGrid, 'numeric');
            app.LabelEditCurrentLabel.ValueChangedFcn = createCallbackFcn(app, @LabelEditCurrentLabelValueChanged, true);
            app.LabelEditCurrentLabel.Value = 1;
            app.Label_3 = uilabel(app.LabelEditGrid);
            app.Label_3.Text = '/';
            app.LabelEditAllLabels = uieditfield(app.LabelEditGrid, 'numeric');
            app.LabelEditAllLabels.Editable = 'off';
            app.LoadLabelEditButton = uibutton(app.LabelEditGrid, 'push');
            app.LoadLabelEditButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLabelEditButtonPushed, true);
            app.LoadLabelEditButton.Visible = 'off';
            app.LoadLabelEditButton.Text = 'Load Label';
            app.ExportLabelsButton = uibutton(app.LabelEditGrid, 'push');
            app.ExportLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLabelsButtonPushed, true);
            app.ExportLabelsButton.Text = 'Export';
            app.ExplainerLabelEdit = uilabel(app.LabelEditGrid);
            app.ExplainerLabelEdit.Text = 'Manually adjust label start/end times, accept or reject labels, edit classifications, and export the edited labels.';
            app.DetectionStateDropDown = uidropdown(app.LabelEditGrid);
            app.DetectionStateDropDown.Items = {'None', 'Accepted', 'Rejected'};
            app.DetectionStateDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionStateDropDownValueChanged, true);
            app.DetectionStateDropDown.Value = 'None';
            app.AcceptedDetectionsEditField = uieditfield(app.LabelEditGrid, 'numeric');
            app.AcceptedDetectionsEditField.Editable = 'off';
            app.RejectedDetectionsEditField = uieditfield(app.LabelEditGrid, 'numeric');
            app.AcceptedLabelsEditFieldLabel = uilabel(app.LabelEditGrid);
            app.AcceptedLabelsEditFieldLabel.Text = 'AD';
            app.RejectedLabelsEditFieldLabel = uilabel(app.LabelEditGrid);
            app.RejectedLabelsEditFieldLabel.Text = 'RD';
            app.AcceptClassificationButton = uibutton(app.LabelEditGrid, 'push');
            app.AcceptClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptClassificationButtonPushed, true);
            app.AcceptClassificationButton.Text = 'Accept Classification';
            app.RejectClassificationButton = uibutton(app.LabelEditGrid, 'push');
            app.RejectClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @RejectClassificationButtonPushed, true);
            app.RejectClassificationButton.Text = 'Reject Classification';
            app.RejectedClassificationsEditField = uieditfield(app.LabelEditGrid, 'numeric');
            app.RCEditFieldLabel = uilabel(app.LabelEditGrid);
            app.RCEditFieldLabel.Text = 'RC';
            app.AcceptedClassificationsEditField = uieditfield(app.LabelEditGrid, 'numeric');
            app.ACEditFieldLabel = uilabel(app.LabelEditGrid);
            app.ACEditFieldLabel.Text = 'AC';
            app.ClassificationStateDropdown = uidropdown(app.LabelEditGrid);
            app.ClassificationStateDropdown.Items = {'None', 'Accepted', 'Rejected'};
            app.ClassificationStateDropdown.ValueChangedFcn = createCallbackFcn(app, @ClassificationStateDropdownValueChanged, true);
            app.ClassificationStateDropdown.Value = 'None';
            app.EditLabelClassificationPanel = uipanel(app.LabelEditGrid);
            app.GridLayout = uigridlayout(app.EditLabelClassificationPanel);
            app.EditLabelClassificationEditField = uieditfield(app.GridLayout, 'text');
            app.EditLabelClassificationEditField.ValueChangedFcn = createCallbackFcn(app, @EditLabelClassificationEditFieldValueChanged, true);
            app.ClassificationDropdown = uidropdown(app.GridLayout);
            app.ClassificationDropdown.ValueChangedFcn = createCallbackFcn(app, @ClassificationDropdownValueChanged, true);
            app.EditClassificationLabel = uilabel(app.GridLayout);
            app.EditClassificationLabel.Text = 'Edit Classification';
            app.VideoSyncTab = uitab(app.TabGroup);
            app.VideoSyncTab.Title = 'Video Sync';
            app.VideoSyncPanel = uipanel(app.VideoSyncTab);
            app.VideoSyncGrid = uigridlayout(app.VideoSyncPanel);
            app.SelectVideoFieldSingle_2 = uieditfield(app.VideoSyncGrid, 'text');
            app.SelectVideoFieldSingle_2.ValueChangedFcn = createCallbackFcn(app, @SelectVideoButtonSinglePushed, true);
            app.SelectVideoButtonSingle_2 = uibutton(app.VideoSyncGrid, 'push');
            app.SelectVideoButtonSingle_2.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonSingle_2Pushed, true);
            app.SelectVideoButtonSingle_2.Text = 'Select Video (.mp4)';
            app.SynchronizationTimeinVideoEditFieldLabel = uilabel(app.VideoSyncGrid);
            app.SynchronizationTimeinVideoEditFieldLabel.Text = 'Synchronization Time in Video';
            app.SynchronizationTimeinVideoEditField = uieditfield(app.VideoSyncGrid, 'numeric');
            app.sLabel = uilabel(app.VideoSyncGrid);
            app.sLabel.Text = '(s)';
            app.SynchronizeVideoButton = uibutton(app.VideoSyncGrid, 'push');
            app.SynchronizeVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SynchronizeVideoButtonPushed, true);
            app.SynchronizeVideoButton.Text = 'Synchronize';
            app.AudioOutputDropDownLabel = uilabel(app.VideoSyncGrid);
            app.AudioOutputDropDownLabel.Text = 'Audio Output';
            app.AudioOutputDropDown = uidropdown(app.VideoSyncGrid);
            app.AudioOutputDropDown.Items = {'Original From Video', 'Original From Audio', 'Sonification'};
            app.AudioOutputDropDown.Value = 'Original From Video';
            app.ExplainerVideoSync = uilabel(app.VideoSyncGrid);
            app.ExplainerVideoSync.Text = 'Synchronize video by trimming it at the sk label, replace its audio with either the original USV or a sonified version.';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsPanel = uipanel(app.SettingsTab);
            app.SettingsGrid = uigridlayout(app.SettingsPanel);
            app.ExportSettingsButton = uibutton(app.SettingsGrid, 'push');
            app.ExportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSettingsButtonPushed, true);
            app.ExportSettingsButton.Text = 'Export Settings';
            app.ImportSettingsButton = uibutton(app.SettingsGrid, 'push');
            app.ImportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSettingsButtonPushed, true);
            app.ImportSettingsButton.Text = 'Import Settings';
            app.SettingsInsideTab = uitabgroup(app.SettingsGrid);
            app.DataInputSettingsTab = uitab(app.SettingsInsideTab);
            app.DataInputSettingsTab.Title = 'Data Input';
            app.DataInputSettingsGrid = uigridlayout(app.DataInputSettingsTab);
            app.DetectormodeLabel = uilabel(app.DataInputSettingsGrid);
            app.DetectormodeLabel.Text = 'Detector mode';
            app.SingleBatchModeButton_2 = uibutton(app.DataInputSettingsGrid, 'push');
            app.SingleBatchModeButton_2.ButtonPushedFcn = createCallbackFcn(app, @SingleBatchModeButtonPushed, true);
            app.SingleBatchModeButton_2.Text = 'Single Mode';
            app.VisualizationSettingsTab = uitab(app.SettingsInsideTab);
            app.VisualizationSettingsTab.Title = 'Visualization';
            app.VisualizationSettingsGrid = uigridlayout(app.VisualizationSettingsTab);
            app.ShowLoadingDialogueCheckBox = uicheckbox(app.VisualizationSettingsGrid);
            app.ShowLoadingDialogueCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLoadingDialogueCheckBoxValueChanged, true);
            app.ShowLoadingDialogueCheckBox.Text = 'Show Loading Dialogue';
            app.SpectrogramWindowSizeEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.SpectrogramWindowSizeEditFieldLabel.Text = 'Spectrogram Window Size';
            app.SpectrogramWindowSizeEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.SpectrogramWindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrogramWindowSizeEditFieldValueChanged, true);
            app.SpectrogramWindowSizeEditField.Value = 1;
            app.SpectrogramWindowOverlapEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.SpectrogramWindowOverlapEditFieldLabel.Text = 'Spectrogram Window Overlap';
            app.SpectrogramWindowOverlapEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.SpectrogramWindowOverlapEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrogramWindowOverlapEditFieldValueChanged, true);
            app.SpectrogramWindowOverlapEditField.Value = 1;
            app.SpectrogramMinimumFrequencyEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.SpectrogramMinimumFrequencyEditFieldLabel.Text = 'Spectrogram Minimum Frequency';
            app.SpectrogramMinimumFrequencyEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.SpectrogramMinimumFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrogramMinimumFrequencyEditFieldValueChanged, true);
            app.SpectrogramMinimumFrequencyEditField.Value = 1;
            app.SpectrogramMaximumFrequencyEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.SpectrogramMaximumFrequencyEditFieldLabel.Text = 'Spectrogram Maximum Frequency';
            app.SpectrogramMaximumFrequencyEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.SpectrogramMaximumFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrogramMaximumFrequencyEditFieldValueChanged, true);
            app.SpectrogramMaximumFrequencyEditField.Value = 1;
            app.Label = uilabel(app.VisualizationSettingsGrid);
            app.Label.Text = '(-)';
            app.Label_2 = uilabel(app.VisualizationSettingsGrid);
            app.Label_2.Text = '(-)';
            app.kHzLabel = uilabel(app.VisualizationSettingsGrid);
            app.kHzLabel.Text = '(kHz)';
            app.kHzLabel_2 = uilabel(app.VisualizationSettingsGrid);
            app.kHzLabel_2.Text = '(kHz)';
            app.ShowLabelsCheckBox = uicheckbox(app.VisualizationSettingsGrid);
            app.ShowLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLabelsCheckBoxValueChanged, true);
            app.ShowLabelsCheckBox.Text = 'Show Labels';
            app.ShowReferenceLabelsCheckBox = uicheckbox(app.VisualizationSettingsGrid);
            app.ShowReferenceLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowReferenceLabelsCheckBoxValueChanged, true);
            app.ShowReferenceLabelsCheckBox.Text = 'Show Reference Labels';
            app.SpectrogramColormapDropDownLabel = uilabel(app.VisualizationSettingsGrid);
            app.SpectrogramColormapDropDownLabel.Text = 'Spectrogram Colormap';
            app.SpectrogramColormapDropDown = uidropdown(app.VisualizationSettingsGrid);
            app.SpectrogramColormapDropDown.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'invgray'};
            app.SpectrogramColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @SpectrogramColormapDropDownValueChanged, true);
            app.SpectrogramColormapDropDown.Value = 'parula';
            app.LabelColorDropDownLabel = uilabel(app.VisualizationSettingsGrid);
            app.LabelColorDropDownLabel.Text = 'Label Color';
            app.LabelColorDropDown = uidropdown(app.VisualizationSettingsGrid);
            app.LabelColorDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'white'};
            app.LabelColorDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelColorDropDownValueChanged, true);
            app.LabelColorDropDown.Value = 'red';
            app.LabelColorDropDown_2Label = uilabel(app.VisualizationSettingsGrid);
            app.LabelColorDropDown_2Label.Text = 'Reference Label Color';
            app.ReferenceLabelColorDropDown = uidropdown(app.VisualizationSettingsGrid);
            app.ReferenceLabelColorDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'white'};
            app.ReferenceLabelColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceLabelColorDropDownValueChanged, true);
            app.ReferenceLabelColorDropDown.Value = 'red';
            app.SonificationSTEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.SonificationSTEditFieldLabel.Text = 'Sonification ST';
            app.SonificationSTEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.SonificationSTEditField.ValueChangedFcn = createCallbackFcn(app, @SonificationSTEditFieldValueChanged, true);
            app.SonificationSTEditField.Value = 1;
            app.SonificationSlowdownEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.SonificationSlowdownEditFieldLabel.Text = 'Sonification Slowdown';
            app.SonificationSlowdownEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.SonificationSlowdownEditField.ValueChangedFcn = createCallbackFcn(app, @SonificationSlowdownEditFieldValueChanged, true);
            app.SonificationSlowdownEditField.Value = 1;
            app.ManualLabelLengthEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.ManualLabelLengthEditFieldLabel.Text = 'Manual Label Length';
            app.ManualLabelLengthEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.ManualLabelLengthEditField.ValueChangedFcn = createCallbackFcn(app, @ManualLabelLengthEditFieldValueChanged, true);
            app.DetectionSettingsTab = uitab(app.SettingsInsideTab);
            app.DetectionSettingsTab.Title = 'Detection';
            app.DetectionSettingsGrid = uigridlayout(app.DetectionSettingsTab);
            app.DetectionSettingsIndividualGroup = uitabgroup(app.DetectionSettingsGrid);
            app.PSDTab = uitab(app.DetectionSettingsIndividualGroup);
            app.PSDTab.Title = 'PSD';
            app.DetectionSettingsGrid_2 = uigridlayout(app.PSDTab);
            app.PSDDetectorSettingsLabel = uilabel(app.DetectionSettingsGrid_2);
            app.PSDDetectorSettingsLabel.Text = 'PSD Detector Settings';
            app.MinimumFrequencyLabel_2 = uilabel(app.DetectionSettingsGrid_2);
            app.MinimumFrequencyLabel_2.Text = 'Minimum Frequency';
            app.PSD_fCutMinEditField = uieditfield(app.DetectionSettingsGrid_2, 'numeric');
            app.PSD_fCutMinEditField.ValueDisplayFormat = '%d';
            app.PSD_fCutMinEditField.ValueChangedFcn = createCallbackFcn(app, @PSD_fCutMinEditFieldValueChanged, true);
            app.MaximumFrequencyLabel = uilabel(app.DetectionSettingsGrid_2);
            app.MaximumFrequencyLabel.Text = 'Maximum Frequency';
            app.PSD_fCutMaxEditField = uieditfield(app.DetectionSettingsGrid_2, 'numeric');
            app.PSD_fCutMaxEditField.ValueDisplayFormat = '%d';
            app.PSD_fCutMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PSD_fCutMaxEditFieldValueChanged, true);
            app.WindowLengthLabel = uilabel(app.DetectionSettingsGrid_2);
            app.WindowLengthLabel.Text = 'Window Length';
            app.PSD_SegmentWindowEditField = uieditfield(app.DetectionSettingsGrid_2, 'numeric');
            app.PSD_SegmentWindowEditField.ValueChangedFcn = createCallbackFcn(app, @PSD_SegmentWindowEditFieldValueChanged, true);
            app.WindowOverlapEditFieldLabel = uilabel(app.DetectionSettingsGrid_2);
            app.WindowOverlapEditFieldLabel.Text = 'Window Overlap';
            app.PSD_WindowOverlapEditField = uieditfield(app.DetectionSettingsGrid_2, 'numeric');
            app.PSD_WindowOverlapEditField.ValueChangedFcn = createCallbackFcn(app, @PSD_WindowOverlapEditFieldValueChanged, true);
            app.SmoothingWindowEditFieldLabel = uilabel(app.DetectionSettingsGrid_2);
            app.SmoothingWindowEditFieldLabel.Text = 'Smoothing Window';
            app.PSD_SmoothingWindowEditField = uieditfield(app.DetectionSettingsGrid_2, 'numeric');
            app.PSD_SmoothingWindowEditField.ValueChangedFcn = createCallbackFcn(app, @PSD_SmoothingWindowEditFieldValueChanged, true);
            app.NoiseTrackingWindowEditFieldLabel = uilabel(app.DetectionSettingsGrid_2);
            app.NoiseTrackingWindowEditFieldLabel.Text = 'Noise Tracking Window';
            app.PSD_NoiseTrackingWindowEditField = uieditfield(app.DetectionSettingsGrid_2, 'numeric');
            app.PSD_NoiseTrackingWindowEditField.ValueChangedFcn = createCallbackFcn(app, @PSD_NoiseTrackingWindowEditFieldValueChanged, true);
            app.LocalTrackingWindowEditFieldLabel = uilabel(app.DetectionSettingsGrid_2);
            app.LocalTrackingWindowEditFieldLabel.Text = 'Local Tracking Window';
            app.PSD_LocalTrackingWindowEditField = uieditfield(app.DetectionSettingsGrid_2, 'numeric');
            app.PSD_LocalTrackingWindowEditField.ValueChangedFcn = createCallbackFcn(app, @PSD_LocalTrackingWindowEditFieldValueChanged, true);
            app.kEditFieldLabel = uilabel(app.DetectionSettingsGrid_2);
            app.kEditFieldLabel.Text = 'k';
            app.PSD_kEditField = uieditfield(app.DetectionSettingsGrid_2, 'numeric');
            app.PSD_kEditField.ValueChangedFcn = createCallbackFcn(app, @PSD_kEditFieldValueChanged, true);
            app.wEditFieldLabel = uilabel(app.DetectionSettingsGrid_2);
            app.wEditFieldLabel.Text = 'w';
            app.PSD_wEditField = uieditfield(app.DetectionSettingsGrid_2, 'numeric');
            app.PSD_wEditField.ValueChangedFcn = createCallbackFcn(app, @PSD_wEditFieldValueChanged, true);
            app.MinEffectivePowerLabel = uilabel(app.DetectionSettingsGrid_2);
            app.MinEffectivePowerLabel.Text = 'Min Effective Power';
            app.PSD_MinPowerEditField = uieditfield(app.DetectionSettingsGrid_2, 'numeric');
            app.PSD_MinPowerEditField.ValueChangedFcn = createCallbackFcn(app, @PSD_MinPowerEditFieldValueChanged, true);
            app.BSCDTab = uitab(app.DetectionSettingsIndividualGroup);
            app.BSCDTab.Title = 'BSCD';
            app.DetectionSettingsGrid_4 = uigridlayout(app.BSCDTab);
            app.BSCDDetectorSettingsLabel = uilabel(app.DetectionSettingsGrid_4);
            app.BSCDDetectorSettingsLabel.Text = 'BSCD Detector Settings';
            app.MinimumFrequencyLabel = uilabel(app.DetectionSettingsGrid_4);
            app.MinimumFrequencyLabel.Text = 'Minimum Frequency';
            app.BSCD_fCutMinEditField = uieditfield(app.DetectionSettingsGrid_4, 'numeric');
            app.BSCD_fCutMinEditField.ValueDisplayFormat = '%d';
            app.BSCD_fCutMinEditField.ValueChangedFcn = createCallbackFcn(app, @BSCD_fCutMinEditFieldValueChanged, true);
            app.fCutMaxLabel = uilabel(app.DetectionSettingsGrid_4);
            app.fCutMaxLabel.Text = 'Maximum Frequency';
            app.BSCD_fCutMaxEditField = uieditfield(app.DetectionSettingsGrid_4, 'numeric');
            app.BSCD_fCutMaxEditField.ValueDisplayFormat = '%d';
            app.BSCD_fCutMaxEditField.ValueChangedFcn = createCallbackFcn(app, @BSCD_fCutMaxEditFieldValueChanged, true);
            app.WindowLengthLabel_2 = uilabel(app.DetectionSettingsGrid_4);
            app.WindowLengthLabel_2.Text = 'Window Length';
            app.BSCD_WindowLengthEditField = uieditfield(app.DetectionSettingsGrid_4, 'numeric');
            app.BSCD_WindowLengthEditField.ValueDisplayFormat = '%d';
            app.BSCD_WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @BSCD_WindowLengthEditFieldValueChanged, true);
            app.WindowLengthLabel_3 = uilabel(app.DetectionSettingsGrid_4);
            app.WindowLengthLabel_3.Text = 'Smoothing Window';
            app.BSCD_SmoothingWindowEditField = uieditfield(app.DetectionSettingsGrid_4, 'numeric');
            app.BSCD_SmoothingWindowEditField.ValueDisplayFormat = '%11.0g';
            app.BSCD_SmoothingWindowEditField.ValueChangedFcn = createCallbackFcn(app, @BSCD_SmoothingWindowEditFieldValueChanged, true);
            app.WindowLengthLabel_4 = uilabel(app.DetectionSettingsGrid_4);
            app.WindowLengthLabel_4.Text = 'Noise Tracking Window';
            app.BSCD_NoiseTrackingWindowEditField = uieditfield(app.DetectionSettingsGrid_4, 'numeric');
            app.BSCD_NoiseTrackingWindowEditField.ValueDisplayFormat = '%11.0g';
            app.BSCD_NoiseTrackingWindowEditField.ValueChangedFcn = createCallbackFcn(app, @BSCD_NoiseTrackingWindowEditFieldValueChanged, true);
            app.WindowLengthLabel_5 = uilabel(app.DetectionSettingsGrid_4);
            app.WindowLengthLabel_5.Text = 'Local Tracking Window';
            app.BSCD_LocalTrackingWindowEditField = uieditfield(app.DetectionSettingsGrid_4, 'numeric');
            app.BSCD_LocalTrackingWindowEditField.ValueDisplayFormat = '%11.0g';
            app.BSCD_LocalTrackingWindowEditField.ValueChangedFcn = createCallbackFcn(app, @BSCD_LocalTrackingWindowEditFieldValueChanged, true);
            app.WindowLengthLabel_6 = uilabel(app.DetectionSettingsGrid_4);
            app.WindowLengthLabel_6.Text = 'k';
            app.BSCD_kEditField = uieditfield(app.DetectionSettingsGrid_4, 'numeric');
            app.BSCD_kEditField.ValueDisplayFormat = '%11.0g';
            app.BSCD_kEditField.ValueChangedFcn = createCallbackFcn(app, @BSCD_kEditFieldValueChanged, true);
            app.WindowLengthLabel_7 = uilabel(app.DetectionSettingsGrid_4);
            app.WindowLengthLabel_7.Text = 'w';
            app.BSCD_wEditField = uieditfield(app.DetectionSettingsGrid_4, 'numeric');
            app.BSCD_wEditField.ValueDisplayFormat = '%11.0g';
            app.BSCD_wEditField.ValueChangedFcn = createCallbackFcn(app, @BSCD_wEditFieldValueChanged, true);
            app.RBDTab = uitab(app.DetectionSettingsIndividualGroup);
            app.RBDTab.Title = 'RBD';
            app.DetectionSettingsGrid_5 = uigridlayout(app.RBDTab);
            app.RBDDetectorSettingsLabel = uilabel(app.DetectionSettingsGrid_5);
            app.RBDDetectorSettingsLabel.Text = 'RBD Detector Settings';
            app.fCutMinLabel = uilabel(app.DetectionSettingsGrid_5);
            app.fCutMinLabel.Text = 'Minimum Frequency';
            app.RBD_fCutMinEditField = uieditfield(app.DetectionSettingsGrid_5, 'numeric');
            app.RBD_fCutMinEditField.ValueDisplayFormat = '%d';
            app.RBD_fCutMinEditField.ValueChangedFcn = createCallbackFcn(app, @RBD_fCutMinEditFieldValueChanged, true);
            app.fCutMinLabel_2 = uilabel(app.DetectionSettingsGrid_5);
            app.fCutMinLabel_2.Text = 'Maximum Frequency';
            app.RBD_fCutMaxEditField = uieditfield(app.DetectionSettingsGrid_5, 'numeric');
            app.RBD_fCutMaxEditField.ValueDisplayFormat = '%d';
            app.RBD_fCutMaxEditField.ValueChangedFcn = createCallbackFcn(app, @RBD_fCutMaxEditFieldValueChanged, true);
            app.fCutMinLabel_3 = uilabel(app.DetectionSettingsGrid_5);
            app.fCutMinLabel_3.Text = 'Window Length';
            app.RBD_wlenEditField = uieditfield(app.DetectionSettingsGrid_5, 'numeric');
            app.RBD_wlenEditField.ValueDisplayFormat = '%d';
            app.RBD_wlenEditField.ValueChangedFcn = createCallbackFcn(app, @RBD_wlenEditFieldValueChanged, true);
            app.fCutMinLabel_4 = uilabel(app.DetectionSettingsGrid_5);
            app.fCutMinLabel_4.Text = 'AR Order Left';
            app.RBD_AR_leftEditField = uieditfield(app.DetectionSettingsGrid_5, 'numeric');
            app.RBD_AR_leftEditField.ValueChangedFcn = createCallbackFcn(app, @RBD_AR_leftEditFieldValueChanged, true);
            app.fCutMinLabel_5 = uilabel(app.DetectionSettingsGrid_5);
            app.fCutMinLabel_5.Text = 'AR Order Right';
            app.RBD_AR_rightEditField = uieditfield(app.DetectionSettingsGrid_5, 'numeric');
            app.RBD_AR_rightEditField.ValueChangedFcn = createCallbackFcn(app, @RBD_AR_rightEditFieldValueChanged, true);
            app.fCutMinLabel_6 = uilabel(app.DetectionSettingsGrid_5);
            app.fCutMinLabel_6.Text = 'Bayesian Evidence Order';
            app.RBD_BayesianEvidenceEditField = uieditfield(app.DetectionSettingsGrid_5, 'numeric');
            app.RBD_BayesianEvidenceEditField.ValueChangedFcn = createCallbackFcn(app, @RBD_BayesianEvidenceEditFieldValueChanged, true);
            app.fCutMinLabel_7 = uilabel(app.DetectionSettingsGrid_5);
            app.fCutMinLabel_7.Text = 'Dynamic Scaling';
            app.RBD_DynamicScalingEditField = uieditfield(app.DetectionSettingsGrid_5, 'numeric');
            app.RBD_DynamicScalingEditField.ValueChangedFcn = createCallbackFcn(app, @RBD_DynamicScalingEditFieldValueChanged, true);
            app.fCutMinLabel_8 = uilabel(app.DetectionSettingsGrid_5);
            app.fCutMinLabel_8.Text = 'Smoothing Window';
            app.RBD_SmoothingWindowEditField = uieditfield(app.DetectionSettingsGrid_5, 'numeric');
            app.RBD_SmoothingWindowEditField.ValueChangedFcn = createCallbackFcn(app, @RBD_SmoothingWindowEditFieldValueChanged, true);
            app.fCutMinLabel_9 = uilabel(app.DetectionSettingsGrid_5);
            app.fCutMinLabel_9.Text = 'Smoothing Threshold';
            app.RBD_SmoothingThresholdEditField = uieditfield(app.DetectionSettingsGrid_5, 'numeric');
            app.RBD_SmoothingThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @RBD_SmoothingThresholdEditFieldValueChanged, true);
            app.fCutMinLabel_10 = uilabel(app.DetectionSettingsGrid_5);
            app.fCutMinLabel_10.Text = 'Amplitude Threshold';
            app.RBD_AmplitudeThresholdEditField = uieditfield(app.DetectionSettingsGrid_5, 'numeric');
            app.RBD_AmplitudeThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @RBD_AmplitudeThresholdEditFieldValueChanged, true);
            app.POSTTab = uitab(app.DetectionSettingsIndividualGroup);
            app.POSTTab.Title = 'POST';
            app.DetectionSettingsGrid_3 = uigridlayout(app.POSTTab);
            app.PostProcessingLabel_2 = uilabel(app.DetectionSettingsGrid_3);
            app.PostProcessingLabel_2.Text = 'Post-Processing';
            app.MaxGapToMergeEditFieldLabel = uilabel(app.DetectionSettingsGrid_3);
            app.MaxGapToMergeEditFieldLabel.Text = 'Max Gap To Merge';
            app.POST_MaxGapToMergeEditField = uieditfield(app.DetectionSettingsGrid_3, 'numeric');
            app.POST_MaxGapToMergeEditField.ValueChangedFcn = createCallbackFcn(app, @POST_MaxGapToMergeEditFieldValueChanged, true);
            app.MinLabelLengthEditFieldLabel = uilabel(app.DetectionSettingsGrid_3);
            app.MinLabelLengthEditFieldLabel.Text = 'Min Label Length';
            app.POST_MinLabelLengthEditField = uieditfield(app.DetectionSettingsGrid_3, 'numeric');
            app.POST_MinLabelLengthEditField.ValueChangedFcn = createCallbackFcn(app, @POST_MinLabelLengthEditFieldValueChanged, true);
            app.LabelEditSettingsTab = uitab(app.SettingsInsideTab);
            app.LabelEditSettingsTab.Title = 'Label Edit';
            app.LabelEditSettingsGrid = uigridlayout(app.LabelEditSettingsTab);
            app.SpectrogramWindowSizeEditField_2Label = uilabel(app.LabelEditSettingsGrid);
            app.SpectrogramWindowSizeEditField_2Label.Text = 'Spectrogram Window Size';
            app.SpectrogramWindowSizeEditField_2 = uieditfield(app.LabelEditSettingsGrid, 'numeric');
            app.SpectrogramWindowSizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @SpectrogramWindowSizeEditFieldValueChanged, true);
            app.SpectrogramWindowSizeEditField_2.Value = 1;
            app.SpectrogramWindowOverlapEditField_2Label = uilabel(app.LabelEditSettingsGrid);
            app.SpectrogramWindowOverlapEditField_2Label.Text = 'Spectrogram Window Overlap';
            app.SpectrogramWindowOverlapEditField_2 = uieditfield(app.LabelEditSettingsGrid, 'numeric');
            app.SpectrogramWindowOverlapEditField_2.ValueChangedFcn = createCallbackFcn(app, @SpectrogramWindowOverlapEditFieldValueChanged, true);
            app.SpectrogramWindowOverlapEditField_2.Value = 1;
            app.SpectrogramMinimumFrequencyEditField_2Label = uilabel(app.LabelEditSettingsGrid);
            app.SpectrogramMinimumFrequencyEditField_2Label.Text = 'Spectrogram Minimum Frequency';
            app.SpectrogramMinimumFrequencyEditField_2 = uieditfield(app.LabelEditSettingsGrid, 'numeric');
            app.SpectrogramMinimumFrequencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @SpectrogramMinimumFrequencyEditFieldValueChanged, true);
            app.SpectrogramMinimumFrequencyEditField_2.Value = 1;
            app.SpectrogramMaximumFrequencyEditField_2Label = uilabel(app.LabelEditSettingsGrid);
            app.SpectrogramMaximumFrequencyEditField_2Label.Text = 'Spectrogram Maximum Frequency';
            app.SpectrogramMaximumFrequencyEditField_2 = uieditfield(app.LabelEditSettingsGrid, 'numeric');
            app.SpectrogramMaximumFrequencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @SpectrogramMaximumFrequencyEditFieldValueChanged, true);
            app.SpectrogramMaximumFrequencyEditField_2.Value = 1;
            app.SpectrogramColormapDropDown_2Label = uilabel(app.LabelEditSettingsGrid);
            app.SpectrogramColormapDropDown_2Label.Text = 'Spectrogram Colormap';
            app.SpectrogramColormapDropDown_2 = uidropdown(app.LabelEditSettingsGrid);
            app.SpectrogramColormapDropDown_2.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'invgray'};
            app.SpectrogramColormapDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SpectrogramColormapDropDownValueChanged, true);
            app.SpectrogramColormapDropDown_2.Value = 'parula';
            app.LabelColorDropDown_2Label_2 = uilabel(app.LabelEditSettingsGrid);
            app.LabelColorDropDown_2Label_2.Text = 'Label Color';
            app.LabelColorDropDown_2 = uidropdown(app.LabelEditSettingsGrid);
            app.LabelColorDropDown_2.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'white'};
            app.LabelColorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LabelColorDropDownValueChanged, true);
            app.LabelColorDropDown_2.Value = 'red';
            app.ReferenceLabelColorDropDown_2Label = uilabel(app.LabelEditSettingsGrid);
            app.ReferenceLabelColorDropDown_2Label.Text = 'Reference Label Color';
            app.ReferenceLabelColorDropDown_2 = uidropdown(app.LabelEditSettingsGrid);
            app.ReferenceLabelColorDropDown_2.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'white'};
            app.ReferenceLabelColorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ReferenceLabelColorDropDownValueChanged, true);
            app.ReferenceLabelColorDropDown_2.Value = 'red';
            app.ManualLabelLengthEditField_2Label = uilabel(app.LabelEditSettingsGrid);
            app.ManualLabelLengthEditField_2Label.Text = 'Manual Label Length';
            app.ManualLabelLengthEditField_2 = uieditfield(app.LabelEditSettingsGrid, 'numeric');
            app.ManualLabelLengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @ManualLabelLengthEditFieldValueChanged, true);
            app.ClassificationsEditFieldLabel = uilabel(app.LabelEditSettingsGrid);
            app.ClassificationsEditFieldLabel.Text = 'Classifications';
            app.ClassificationsEditField = uieditfield(app.LabelEditSettingsGrid, 'text');
            app.ClassificationsEditField.ValueChangedFcn = createCallbackFcn(app, @ClassificationsEditFieldValueChanged, true);
            app.VideoSyncSettingsTab = uitab(app.SettingsInsideTab);
            app.VideoSyncSettingsTab.Title = 'Video Sync';
            app.VideoSyncSettingsGrid = uigridlayout(app.VideoSyncSettingsTab);
            app.ForfuturefeaturesLabel = uilabel(app.VideoSyncSettingsGrid);
            app.ForfuturefeaturesLabel.Text = 'For future features';
            app.ExportSettingsTab = uitab(app.SettingsInsideTab);
            app.ExportSettingsTab.Title = 'Metrics';
            app.ExportSettingsGrid = uigridlayout(app.ExportSettingsTab);
            app.ForfuturefeaturesLabel_2 = uilabel(app.ExportSettingsGrid);
            app.ForfuturefeaturesLabel_2.Text = 'For future features';
            app.OtherSettingsTab = uitab(app.SettingsInsideTab);
            app.OtherSettingsTab.Title = 'Other';
            app.OtherSettingsGrid = uigridlayout(app.OtherSettingsTab);
            app.ThemeDropDownLabel = uilabel(app.OtherSettingsGrid);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.OtherSettingsGrid);
            app.ThemeDropDown.Items = {'Light', 'Gray'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Value = 'Light';
            app.SettingsMenuPanel = uipanel(app.SettingsTab);
            app.SettingsMenuGrid = uigridlayout(app.SettingsMenuPanel);
            app.SettingsDropDownLabel = uilabel(app.SettingsMenuGrid);
            app.SettingsDropDownLabel.Text = 'Settings';
            app.SettingsDropDown = uidropdown(app.SettingsMenuGrid);
            app.SettingsDropDown.Items = {'Data Input', 'Visualization', 'Metrics', 'Detection', 'Label Edit', 'Video Sync', 'Other'};
            app.SettingsDropDown.ValueChangedFcn = createCallbackFcn(app, @SettingsDropDownValueChanged, true);
            app.SettingsDropDown.Value = 'Data Input';
            app.SettingsText = uilabel(app.SettingsMenuGrid);
            app.SettingsText.Text = '';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.InfoPanel = uipanel(app.InfoTab);
            app.InfoGrid = uigridlayout(app.InfoPanel);
            app.Image = uiimage(app.InfoGrid);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo', 'squeakpeak_logo.png');
            app.NameOfAppLabel = uilabel(app.InfoGrid);
            app.NameOfAppLabel.Text = 'Squeak Peek Studio';
            app.DescriptionLabel = uilabel(app.InfoGrid);
            app.DescriptionLabel.Text = 'Software for Visualization, Segmentation, and Sonification of Ultrasonic Vocalizations of Laboratory Rats ';
            app.Text1Label = uilabel(app.InfoGrid);
            app.Text1Label.Text = 'This application was developed as part of a Master’s Thesis in Electronics and Communications - Audiovisual Technology and Signal';
            app.Text2Label = uilabel(app.InfoGrid);
            app.Text2Label.Text = 'processing at the Czech Technical University in Prague, Faculty of Electrical Engineering (CTU FEE) in collaboration with National Institute of';
            app.Text3Label = uilabel(app.InfoGrid);
            app.Text3Label.Text = 'Mental Health in Klecany, Czech Republic.';
            app.AuthorLabel = uilabel(app.InfoGrid);
            app.AuthorLabel.Text = 'Author';
            app.AuthorNameLabel = uilabel(app.InfoGrid);
            app.AuthorNameLabel.Text = 'Antonín Gazda';
            app.SupervisorLabel = uilabel(app.InfoGrid);
            app.SupervisorLabel.Text = 'Supervisor';
            app.SupervisorNameLabel = uilabel(app.InfoGrid);
            app.SupervisorNameLabel.Text = 'prof. Ing. Roman Čmejla, CSc.';
            app.AcademicYearLabel = uilabel(app.InfoGrid);
            app.AcademicYearLabel.Text = 'Academic Year';
            app.YearLabel = uilabel(app.InfoGrid);
            app.YearLabel.Text = '2024/2025';
            app.AuthorDepartmentLabel = uilabel(app.InfoGrid);
            app.AuthorDepartmentLabel.Text = 'Department of Radioelectronics';
            app.SupervisorDepartmentLabel = uilabel(app.InfoGrid);
            app.SupervisorDepartmentLabel.Text = 'Department of Circuit Theory';
            app.OpenDocumentationButton = uibutton(app.InfoGrid, 'push');
            app.OpenDocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDocumentationButtonPushed, true);
            app.OpenDocumentationButton.Text = 'Open Documentation';
            app.OpenMastersThesisButton = uibutton(app.InfoGrid, 'push');
            app.OpenMastersThesisButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMastersThesisButtonPushed, true);
            app.OpenMastersThesisButton.Text = 'Open Master''s Thesis';
            app.OpenSourceCodeButton = uibutton(app.InfoGrid, 'push');
            app.OpenSourceCodeButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSourceCodeButtonPushed, true);
            app.OpenSourceCodeButton.Text = 'Open Source Code';
            app.MatlabVersionLabel = uilabel(app.InfoGrid);
            app.MatlabVersionLabel.Text = 'MATLAB version';
            app.ApplicationVersionLabel = uilabel(app.InfoGrid);
            app.ApplicationVersionLabel.Text = 'Application version';
            app.MatlabVersion = uilabel(app.InfoGrid);
            app.MatlabVersion.Text = '23.2.0.2380103 (R2023b) Update 1';
            app.AppVersion = uilabel(app.InfoGrid);
            app.AppVersion.Text = 'version defined at runtime';
            app.AuthorEmailHyperlink = uihyperlink(app.InfoGrid);
            app.AuthorEmailHyperlink.URL = 'mailto:me@antoningazda.com';
            app.AuthorEmailHyperlink.Text = {'me@antoningazda.com'; ''};
            app.NIMHLogo = uiimage(app.InfoGrid);
            app.NIMHLogo.URL = 'https://www.nudz.cz/';
            app.NIMHLogo.AltText = 'NIMH LOGO';
            app.NIMHLogo.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo', 'nimh_logo.png');
            app.CTULogo = uiimage(app.InfoGrid);
            app.CTULogo.URL = 'https://fel.cvut.cz';
            app.CTULogo.AltText = 'CTU FEE LOGO';
            app.CTULogo.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo', 'fee_logo.jpg');
            app.ButtonsMenuPanel = uipanel(app.SqueakPeekStudioUIFigure);
            app.ButtonsMenuGrid = uigridlayout(app.ButtonsMenuPanel);
            app.DataInputButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.DataInputButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.DataInputButton.Text = 'Data Input';
            app.DetectionButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.DetectionButton.Text = 'Detection';
            app.LabelEditButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.LabelEditButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.LabelEditButton.Text = 'Label Edit';
            app.VideoSyncButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.VideoSyncButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.VideoSyncButton.Text = 'Video Sync';
            app.SettingsButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.SettingsButton.Text = 'Settings';
            app.InfoButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.InfoButton.Text = 'Info';
            app.MetricsButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.MetricsButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.MetricsButton.Text = 'Metrics';
            app.VisualizationButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.VisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.VisualizationButton.Text = 'Visualization';
            % Show the figure after all components are created
            app.SqueakPeekStudioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SqueakPeekStudio

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SqueakPeekStudioUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SqueakPeekStudioUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SqueakPeekStudioUIFigure)
        end
    end
end